classdef scripthelpTableDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScriptHelpFigure  matlab.ui.Figure
        UITable1          matlab.ui.control.Table
        UITable2          matlab.ui.control.Table
        TableLabel        matlab.ui.control.Label
        CellArrayLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            list = assembler.opcodelist(:,1:7);
            result = logical(cell2mat(list(:,6)));
            list(:,6) = mat2cell(result, ones(length(result),1));
            jump = logical(cell2mat(list(:,7)));
            list(:,7) = mat2cell(jump, ones(length(jump),1));
            table = cell2table(list);
            
            bgColor = ones(size(list,1), 3);
            bgColor(3,:) = [0 1 0];
            app.UITable1.Data = table;
            app.UITable1.BackgroundColor = bgColor;
            
            
            app.UITable2.Data = assembler.opcodelist(:,1:7);
            app.UITable2.ColumnFormat = {'char', 'numeric', 'numeric', 'numeric', 'char', 'logical','logical'};
            app.UITable2.BackgroundColor = bgColor;
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScriptHelpFigure = uifigure('Visible', 'off');
            app.ScriptHelpFigure.Name = 'Script Help';
            app.UITable2 = uitable(app.ScriptHelpFigure);
            app.UITable2.ColumnName = {'OPCODE'; 'Byte'; 'Min'; 'Max'; 'Description'; 'Res'; 'Jump'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = [true true true true true true true];
            app.UITable2.ColumnEditable = [false false false false false false false];
            app.UITable1 = uitable(app.ScriptHelpFigure);
            app.UITable1.ColumnName = {'OPCODE'; 'Byte'; 'Min'; 'Max'; 'Description'; 'Res'; 'Jump'};
            app.UITable1.RowName = {};
            app.UITable1.ColumnSortable = [true true true true true true true];
            app.UITable1.ColumnEditable = [false false false false false false false];
            app.TableLabel = uilabel(app.ScriptHelpFigure);
            app.TableLabel.Text = 'Table';
            app.CellArrayLabel = uilabel(app.ScriptHelpFigure);
            app.CellArrayLabel.Text = 'CellArray';
            % Show the figure after all components are created
            app.ScriptHelpFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scripthelpTableDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScriptHelpFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScriptHelpFigure)
        end
    end
end