classdef scriptsched < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScriptSchedulerUIFigure  matlab.ui.Figure
        OrderPanel               matlab.ui.container.Panel
        ScriptNumberLabel        matlab.ui.control.Label
        ScriptExecutionPeriodEditFieldLabel  matlab.ui.control.Label
        RefreshSchedulerButton   matlab.ui.control.Button
        msLabel                  matlab.ui.control.Label
        ScriptPeriodEditField    matlab.ui.control.NumericEditField
        SchedulerLabel           matlab.ui.control.Label
        OrdLabel                 matlab.ui.control.Label
        WriteButton              matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        StartUpPanel             matlab.ui.container.Panel
        StartupRefreshButton     matlab.ui.control.Button
        StartupEnabledCheckBox   matlab.ui.control.CheckBox
        StartupEditField         matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        RPDOPanel                matlab.ui.container.Panel
        RPDORefreshButton        matlab.ui.control.Button
        RPDO8SPEditField         matlab.ui.control.NumericEditField
        RPDO7SPEditField         matlab.ui.control.NumericEditField
        RPDO6SPEditField         matlab.ui.control.NumericEditField
        RPDO5SPEditField         matlab.ui.control.NumericEditField
        RPDO4SPEditField         matlab.ui.control.NumericEditField
        RPDO3SPEditField         matlab.ui.control.NumericEditField
        RPDO2SPEditField         matlab.ui.control.NumericEditField
        RPDO1SPEditField         matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        AddressLabel             matlab.ui.control.Label
        RPDO8AddrEditField       matlab.ui.control.NumericEditField
        RPDO8Label               matlab.ui.control.Label
        RPDO7AddrEditField       matlab.ui.control.NumericEditField
        RPDO7Label               matlab.ui.control.Label
        RPDO6AddrEditField       matlab.ui.control.NumericEditField
        RPDO6Label               matlab.ui.control.Label
        RPDO5AddrEditField       matlab.ui.control.NumericEditField
        RPDO5Label               matlab.ui.control.Label
        RPDO4AddrEditField       matlab.ui.control.NumericEditField
        RPDO4Label               matlab.ui.control.Label
        RPDO3AddrEditField       matlab.ui.control.NumericEditField
        RPDO3Label               matlab.ui.control.Label
        RPDO2AddrEditField       matlab.ui.control.NumericEditField
        RPDO2Label               matlab.ui.control.Label
        RPDO1AddrEditField       matlab.ui.control.NumericEditField
        RPDO1Label               matlab.ui.control.Label
        RPDOEnabledCheckBox      matlab.ui.control.CheckBox
        RTCPanel                 matlab.ui.container.Panel
        RTCRefreshButton         matlab.ui.control.Button
        SetImplantTimeButton     matlab.ui.control.Button
        GetComputerTimeButton    matlab.ui.control.Button
        GetImplantTimeButton     matlab.ui.control.Button
        RTCEditField             matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
        Alarm4SPEditField        matlab.ui.control.NumericEditField
        Alarm3SPEditField        matlab.ui.control.NumericEditField
        Alarm2SPEditField        matlab.ui.control.NumericEditField
        Alarm1SPEditField        matlab.ui.control.NumericEditField
        HHMMSSLabel              matlab.ui.control.Label
        Alarm4TimeEditField      matlab.ui.control.EditField
        Alarm4EditFieldLabel     matlab.ui.control.Label
        Alarm3TimeEditField      matlab.ui.control.EditField
        Alarm3EditFieldLabel     matlab.ui.control.Label
        Alarm2TimeEditField      matlab.ui.control.EditField
        Alarm2EditFieldLabel     matlab.ui.control.Label
        Alarm1TimeEditField      matlab.ui.control.EditField
        Alarm1EditFieldLabel     matlab.ui.control.Label
        AlarmEnabledCheckBox     matlab.ui.control.CheckBox
        noscriptinslotLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        SED = []; %handle to scriptedit
        reqOrder;
    end
    
    properties (Access = private)
        colCurrent = 1;
        colNew = 2;
        changedOD = false;
    end
    
    methods (Access = private)
        
        function updateScheduler(app)
            resp = double(app.SED.nnp.read(7, '2001', 5, 'uint32'));
            if length(resp)==1
                app.ScriptPeriodEditField.Value = resp;
                app.RefreshSchedulerButton.BackgroundColor = [.96 .96 .96];
            else
                app.RefreshSchedulerButton.BackgroundColor = [1 0 0];
            end
            
            if isempty(app.UITable.Data)
                data = cell(25,2);
                if ~isempty(app.SED.scripts) && isfield(app.SED.scripts, 'order') && ~isempty(app.SED.scripts.order)
                    app.reqOrder = app.SED.scripts.order;
                end
            else
                data = app.UITable.Data;
            end
            resp = double(app.SED.nnp.read(7, '1f56', 1));
            if length(resp) ~=25
                app.RefreshSchedulerButton.BackgroundColor = [1 0 0];
            else
                app.RefreshSchedulerButton.BackgroundColor = [.96 .96 .96];
                data(:,app.colCurrent) = num2cell(resp');
                if isempty(app.reqOrder)
                    app.reqOrder = resp'; %default New values are same as current
                end
            end
            data(:,app.colNew) = num2cell(app.reqOrder);
            app.UITable.Data = data;
        end
        
        function updateStartup(app, checkboxOnly)
            if nargin<2
                checkboxOnly = false;
            end
            resp = app.SED.nnp.read(7, '2001', 1, 'uint32');
            if length(resp)==1
                app.StartupEnabledCheckBox.Value = logical(bitget(resp, 9));
                app.StartupRefreshButton.BackgroundColor = [.96 .96 .96];
                if checkboxOnly
                    return
                end
                resp = app.SED.nnp.read(7, '2001', 3, 'uint8');
                if length(resp)==1
                    app.StartupEditField.Value = double(resp);
                else
                    app.StartupRefreshButton.BackgroundColor = [1 0 0];
                end
            else
               app.StartupRefreshButton.BackgroundColor = [1 0 0];
            end
        end
        
        function updateRPDO(app, checkboxOnly)
            if nargin<2
                checkboxOnly = false;
            end
            SP =  [app.RPDO1SPEditField,   app.RPDO2SPEditField,   app.RPDO3SPEditField,   app.RPDO4SPEditField,...
                   app.RPDO5SPEditField,   app.RPDO6SPEditField,   app.RPDO7SPEditField,   app.RPDO8SPEditField  ];
            Addr =[app.RPDO1AddrEditField, app.RPDO2AddrEditField, app.RPDO3AddrEditField, app.RPDO4AddrEditField,...
                   app.RPDO5AddrEditField, app.RPDO6AddrEditField, app.RPDO7AddrEditField, app.RPDO8AddrEditField];
            resp = app.SED.nnp.read(7, '2001', 1, 'uint32');
            if length(resp)==1
                app.RPDOEnabledCheckBox.Value = logical(bitget(resp, 4) && bitget(resp, 7));
                app.RPDORefreshButton.BackgroundColor = [.96 .96 .96];
                if checkboxOnly
                    return
                end
                for i=1:8
                    resp = app.SED.nnp.read(7, ['140' num2str(i-1)], 1, 'uint32');
                    if length(resp)==1
                        Addr(i).Value = double(resp);
                        Addr(i).BackgroundColor = [1 1 1];
                    else
                        Addr(i).BackgroundColor = [1 0 0];
                        app.RPDORefreshButton.BackgroundColor = [1 1 0];
                    end
                    resp = app.SED.nnp.read(7, ['140' num2str(i-1)], 4, 'uint8');
                    if length(resp)==1
                        SP(i).Value = double(resp);
                        SP(i).BackgroundColor = [1 1 1];
                    else
                        SP(i).BackgroundColor = [1 0 0];
                        app.RPDORefreshButton.BackgroundColor = [1 1 0];
                    end
                end
            else
               app.RPDORefreshButton.BackgroundColor = [1 0 0];
            end
        end
        
        function updateAlarms(app, checkboxOnly)
            if nargin<2
                checkboxOnly = false;
            end
            SP =  [app.Alarm1SPEditField,   app.Alarm2SPEditField,   app.Alarm3SPEditField,   app.Alarm4SPEditField];
            Time =[app.Alarm1TimeEditField, app.Alarm2TimeEditField, app.Alarm3TimeEditField, app.Alarm4TimeEditField];
            resp = app.SED.nnp.read(7, '2001', 1, 'uint32');
            if length(resp)==1
                app.AlarmEnabledCheckBox.Value = logical(bitget(resp, 4) & bitget(resp, 7));
                app.RTCRefreshButton.BackgroundColor = [.96 .96 .96];
                if checkboxOnly
                    return
                end
                resp = app.SED.nnp.read(7, '2004', 3, 4, 'uint32');
                if length(resp)==4
                    for i=1:4
                        [Time(i).Value, sp] = app.getTimeStringFromAlarmValue(resp(i));
                        Time(i).BackgroundColor = [1 1 1];
                        SP(i).Value = sp;
                        SP(i).BackgroundColor = [1 1 1];
                    end
                else
                    for i=1:4
                        SP(i).BackgroundColor = [1 0 0];
                        Alarm(i).BackgroundColor = [1 0 0];
                    end
                    app.RTCRefreshButton.BackgroundColor = [1 1 0];
                end
            else
               app.RTCRefreshButton.BackgroundColor = [1 0 0];
            end
            app.updateRTC();
        end
        
        function [timeStr, sp] = getTimeStringFromAlarmValue(app, val)
            sec = double(bitand(val, hex2dec('3F'))); %lowest 6 bits
            min = double(bitshift(bitand(val, hex2dec('0FC0')), -6)); %next 6 bits
            hour = double(bitshift(bitand(val, hex2dec('1F000')), -12)); %next 5 bits
            sp = double(bitshift(bitand(val, hex2dec('3E0000')), -17)); %next 5 bits
            timeStr = sprintf('%02u:%02u:%02u', hour, min, sec);
        end
    
        function val = getAlarmValueFromTimeString(app, timeStr, sp)
            timeArray = sscanf(timeStr, '%u:%u:%u');
            if length(timeArray) == 3
                hour = timeArray(1);
                min = timeArray(2);
                sec = timeArray(3);
                if hour>=0 && hour<24 && min >=0 && min < 60 && sec >=0 && sec <60
                    val = sp*2^17 + hour*2^12 + min*2^6 + sec;
                else
                    val = []; 
                end
            else
                val = []; 
            end
            
        end
    
        function [timeStr, sp] = getTimeStringFromTimeValue(app, val)
            sec = double(bitand(val, hex2dec(              '3F'))      ); %bits  5:0
            min = double(bitshift(bitand(val,    hex2dec('3F00')),  -8)); %bits 13:8
            hour = double(bitshift(bitand(val, hex2dec('1F0000')), -16)); %bits 20:16
            timeStr = sprintf('%02u:%02u:%02u', hour, min, sec);
        end
    
        function val = getTimeValueFromTimeString(app, timeStr)

            timeArray = sscanf(timeStr, '%u:%u:%u');
            if length(timeArray) == 3
                hour = timeArray(1);
                min = timeArray(2);
                sec = timeArray(3);
                if hour>=0 && hour<24 && min >=0 && min < 60 && sec >=0 && sec <60
                    val = hour*2^16 + min*2^8 + sec;
                else
                    val = []; 
                end
            else
                val = []; 
            end
            
        end
        
        function setAlarm(app, AlarmStr, sp, num)
            val = getAlarmValueFromTimeString(app, AlarmStr, sp);
            if ~isempty(val)
                app.changedOD = true;
                app.SED.nnp.write(7, '2004', 2+num, val, 'uint32');
            else
                msgbox(sprintf('Invalid time string for Alarm %u', num))
            end
        end
        
        function updateRTC(app)
            resp = app.SED.nnp.read(7, '2004', 2, 'uint32');
            if length(resp)==1
                app.RTCEditField.Value = app.getTimeStringFromTimeValue(resp);
                app.RTCEditField.BackgroundColor = [1 1 1];
                app.RTCEditField.FontAngle = 'normal';
            else
                app.RTCEditField.BackgroundColor = [1 0 0];
            end
        end
        
        function setRPDOsp(app, val, num)
            app.changedOD = true;
            app.SED.nnp.write(7, ['140' num2str(num-1)], 4, val, 'uint8');
        end
        
        function enableToolTips(app)
            app.OrderPanel.Tooltip = sprintf(['Scripts (#) are queued every period in the indicated order.'...
                'To make a script execute less frequently use a global counter within'...
                'the script and exit early if the script does not need to execute.\n\n' ...
                'Period is written to 0x2001.5 when changed.\n\nRefresh reads script execution '...
                'period 0x2001.5 and script order 0x1F56']);
            app.StartUpPanel.Tooltip = sprintf(['Script(#) is executed on power up of the Implant.'...
                'This script is usually used to start other scripts. On reset, the Implant clears all '...
                'run and error status information']);
            app.RPDOPanel.Tooltip = sprintf(['Script (#) is queued when data received by PDO.\n'...
                'Configure the PDO addresses and data mapping elsewhere']);
            app.RTCPanel.Tooltip = 'Script (#) is queued when Real-time Clock reaches Alarm Time';
        end
    
        function disableToolTips(app)
            app.OrderPanel.Tooltip = '';
            app.StartUpPanel.Tooltip = '';
            app.RPDOPanel.Tooltip = '';
            app.RTCPanel.Tooltip = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SED)
            app.SED = SED;
            app.updateScheduler();
            app.updateStartup();
            app.updateRPDO();
            app.updateAlarms();
            if app.SED.enableToolTips
                app.enableToolTips();
            else
                app.disableToolTips();
            end
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)

            data = cell2mat(app.UITable.Data(:, app.colNew));

            if all(isnumeric(data)) && all(data <= 25) && all(data >= 0) %order = 0 means no execution in round robin.  Script c
                app.reqOrder = data;
                app.changedOD = true;
                resp = app.SED.nnp.write(7, '1f56', 1, data');
                if isequal(resp, 0)
                    app.WriteButton.BackgroundColor = [.96 .96 .96];
                else
                    app.WriteButton.BackgroundColor = [1 0 0];
                end
                app.updateScheduler();
                if ~isempty(app.SED)
                    app.SED.populateActiveTable();
                end
            else
                msgbox('At least one value in New column is outside range 0-25')
            end
        end

        % Close request function: ScriptSchedulerUIFigure
        function ScriptSchedulerUIFigureCloseRequest(app, event)

            if ~isempty(app.UITable.Data) && ~isequal(app.reqOrder, cell2mat(app.UITable.Data(:, app.colNew))) %unapplied changes
                userResp = questdlg('Values were changed in table but not sent to Implant. Do you want to go back and apply changes?');
                if ~isequal(userResp, 'No')
                    return
                end
            end
                
            if app.changedOD
                userResp = questdlg('Do you want to save modified Object Dictionary values as defaults?');
                if isequal(userResp, 'Yes')
                    app.SED.nnp.nmt(7, '0a');
                elseif isequal(userResp, 'No')
                    %nothing to do
                else %cancel
                    return %don't close app
                end
            end
                
            if ~isempty(app.reqOrder)
                
                if isempty(app.SED.scripts) || ~isfield(app.SED.scripts, 'order') || ....
                         ~isequal(app.reqOrder, app.SED.scripts.order) %changes tp file order
     
                    userResp = questdlg('Do you want to save the new script order values to the Script Library file?');
                    if isequal(userResp, 'Yes')
                        app.SED.scripts.order = app.reqOrder;
                    elseif isequal(userResp, 'No')
                        %nothing to do
                    else %cancel
                        return %don't close app
                    end
                end
            end

            delete(app)
            
        end

        % Button pushed function: RefreshSchedulerButton
        function RefreshSchedulerButtonPushed(app, event)
            app.updateScheduler();
        end

        % Value changed function: ScriptPeriodEditField
        function ScriptPeriodEditFieldValueChanged(app, event)
            period = app.ScriptPeriodEditField.Value;
            resp = app.SED.nnp.write(7, '2001', 5, period, 'uint32');
            if ~isequal(resp, 0) %did not get acknowledgement, read it back
                app.RefreshSchedulerButtonPushed([]);
            end
            
        end

        % Button pushed function: StartupRefreshButton
        function StartupRefreshButtonPushed(app, event)
            app.updateStartup();
        end

        % Button pushed function: RPDORefreshButton
        function RPDORefreshButtonPushed(app, event)
            app.updateRPDO();
        end

        % Button pushed function: RTCRefreshButton
        function RTCRefreshButtonPushed(app, event)
            app.updateAlarms();
        end

        % Value changed function: Alarm1SPEditField, Alarm1TimeEditField
        function Alarm1TimeEditFieldValueChanged(app, event)
            app.setAlarm(app.Alarm1TimeEditField.Value, app.Alarm1SPEditField.Value, 1);
        end

        % Value changed function: Alarm2SPEditField, Alarm2TimeEditField
        function Alarm2TimeEditFieldValueChanged(app, event)
            app.setAlarm(app.Alarm2TimeEditField.Value, app.Alarm2SPEditField.Value, 2);
        end

        % Value changed function: Alarm3SPEditField, Alarm3TimeEditField
        function Alarm3TimeEditFieldValueChanged(app, event)
            app.setAlarm(app.Alarm3TimeEditField.Value, app.Alarm3SPEditField.Value, 3);
        end

        % Value changed function: Alarm4SPEditField, Alarm4TimeEditField
        function Alarm4TimeEditFieldValueChanged(app, event)
            app.setAlarm(app.Alarm4TimeEditField.Value, app.Alarm4SPEditField.Value, 4);
        end

        % Button pushed function: GetComputerTimeButton
        function GetComputerTimeButtonPushed(app, event)
            app.RTCEditField.Value = datestr(datetime, 'HH:MM:SS');
            app.RTCEditField.FontAngle = 'italic';
        end

        % Button pushed function: SetImplantTimeButton
        function SetImplantTimeButtonPushed(app, event)
            val = app.getTimeValueFromTimeString(app.RTCEditField.Value);
            resp = app.SED.nnp.nmt(7, hex2dec('97')); %Halt RTC
            if isequal(resp, hex2dec('97'))
                resp = app.SED.nnp.write(7, '2004', 2, val, 'uint32');
                if isequal(resp, 0)
                    resp = app.SED.nnp.nmt(7, hex2dec('88')); %Restart RTC
                    if isequal(resp, hex2dec('88'))
                        err = false;
                    else
                        err = true;
                    end
                else
                    err = true;
                end
            else
                err = true;
            end
           if err
               app.SetImplantTimeButton.BackgroundColor = [1 0 0];
           else
               app.SetImplantTimeButton.BackgroundColor = [.96 .96 .96];
           end
            
            app.updateRTC();
        end

        % Button pushed function: GetImplantTimeButton
        function GetImplantTimeButtonPushed(app, event)
            app.updateRTC();
        end

        % Value changed function: RPDO1SPEditField
        function RPDO1SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO1SPEditField.Value, 1);
        end

        % Value changed function: RPDO2SPEditField
        function RPDO2SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO2SPEditField.Value, 2);
        end

        % Value changed function: RPDO3SPEditField
        function RPDO3SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO3SPEditField.Value, 3);
        end

        % Value changed function: RPDO4SPEditField
        function RPDO4SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO4SPEditField.Value, 4);
        end

        % Value changed function: RPDO5SPEditField
        function RPDO5SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO5SPEditField.Value, 5);
        end

        % Value changed function: RPDO6SPEditField
        function RPDO6SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO6SPEditField.Value, 6);      
        end

        % Value changed function: RPDO7SPEditField
        function RPDO7SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO7SPEditField.Value, 7);
        end

        % Value changed function: RPDO8SPEditField
        function RPDO8SPEditFieldValueChanged(app, event)
            app.setRPDOsp(app.RPDO8SPEditField.Value, 8);
        end

        % Value changed function: StartupEnabledCheckBox
        function StartupEnabledCheckBoxValueChanged(app, event)

            resp = app.SED.nnp.read(7, '2001', 1, 'uint32');
            if length(resp)==1
                if app.StartupEnabledCheckBox.Value
                    val = bitset(resp, 9);
                else
                    val = bitset(resp, 9, 0);
                end
                app.changedOD = true;
                resp = app.SED.nnp.write(7, '2001', 1, val, 'uint32');
                if ~isequal(resp, 0)
                    app.updateStartup();
                end
            else
                app.updateStartup(true);
            end
        end

        % Value changed function: RPDOEnabledCheckBox
        function RPDOEnabledCheckBoxValueChanged(app, event)
            resp = app.SED.nnp.read(7, '2001', 1, 'uint32');
            if length(resp)==1
                if app.RPDOEnabledCheckBox.Value
                    val = bitset(resp, 4);
                    val = bitset(val, 7);
                else
                    val = bitset(resp, 4, 0);
                    val = bitset(val, 7, 0);
                end
                app.changedOD = true;
                resp = app.SED.nnp.write(7, '2001', 1, val, 'uint32');
                if ~isequal(resp, 0)
                    app.updateRPDO(true);
                end
            else
                app.updateRPDO(true);
            end
            
        end

        % Value changed function: AlarmEnabledCheckBox
        function AlarmEnabledCheckBoxValueChanged(app, event)
            resp = app.SED.nnp.read(7, '2001', 1, 'uint32');
            if length(resp)==1
                if app.AlarmEnabledCheckBox.Value
                    val = bitset(resp, 3);
                else
                    val = bitset(resp, 3,0);
                end
                app.changedOD = true;
                resp = app.SED.nnp.write(7, '2001', 1, val, 'uint32');
                if ~isequal(resp, 0)
                    app.updateAlarms(true);
                end
            else
                app.updateAlarms(true);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScriptSchedulerUIFigure = uifigure('Visible', 'off');
            app.ScriptSchedulerUIFigure.Name = 'Script Scheduler';
            app.ScriptSchedulerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScriptSchedulerUIFigureCloseRequest, true);
            app.noscriptinslotLabel = uilabel(app.ScriptSchedulerUIFigure);
            app.noscriptinslotLabel.Text = '(#: 0 = no script in slot)';
            app.RTCPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.RTCPanel.Tooltip = {''};
            app.AlarmEnabledCheckBox = uicheckbox(app.RTCPanel);
            app.AlarmEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlarmEnabledCheckBoxValueChanged, true);
            app.AlarmEnabledCheckBox.Text = 'Real-Time Clock';
            app.Alarm1EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm1EditFieldLabel.Text = 'Alarm 1';
            app.Alarm1TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.Alarm1TimeEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm1TimeEditFieldValueChanged, true);
            app.Alarm2EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm2EditFieldLabel.Text = 'Alarm 2';
            app.Alarm2TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.Alarm2TimeEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm2TimeEditFieldValueChanged, true);
            app.Alarm3EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm3EditFieldLabel.Text = 'Alarm 3';
            app.Alarm3TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.Alarm3TimeEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm3TimeEditFieldValueChanged, true);
            app.Alarm4EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm4EditFieldLabel.Text = 'Alarm 4';
            app.Alarm4TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.Alarm4TimeEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm4TimeEditFieldValueChanged, true);
            app.HHMMSSLabel = uilabel(app.RTCPanel);
            app.HHMMSSLabel.Text = 'HH:MM:SS';
            app.Alarm1SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm1SPEditField.Limits = [0 25];
            app.Alarm1SPEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm1TimeEditFieldValueChanged, true);
            app.Alarm2SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm2SPEditField.Limits = [0 25];
            app.Alarm2SPEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm2TimeEditFieldValueChanged, true);
            app.Alarm3SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm3SPEditField.Limits = [0 25];
            app.Alarm3SPEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm3TimeEditFieldValueChanged, true);
            app.Alarm4SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm4SPEditField.Limits = [0 25];
            app.Alarm4SPEditField.ValueChangedFcn = createCallbackFcn(app, @Alarm4TimeEditFieldValueChanged, true);
            app.Label = uilabel(app.RTCPanel);
            app.Label.Text = '#';
            app.RTCEditField = uieditfield(app.RTCPanel, 'text');
            app.RTCEditField.Value = '00:00:00';
            app.GetImplantTimeButton = uibutton(app.RTCPanel, 'push');
            app.GetImplantTimeButton.ButtonPushedFcn = createCallbackFcn(app, @GetImplantTimeButtonPushed, true);
            app.GetImplantTimeButton.Text = 'Get Implant Time';
            app.GetComputerTimeButton = uibutton(app.RTCPanel, 'push');
            app.GetComputerTimeButton.ButtonPushedFcn = createCallbackFcn(app, @GetComputerTimeButtonPushed, true);
            app.GetComputerTimeButton.Text = 'Get Computer Time';
            app.SetImplantTimeButton = uibutton(app.RTCPanel, 'push');
            app.SetImplantTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SetImplantTimeButtonPushed, true);
            app.SetImplantTimeButton.Text = 'Set Implant Time';
            app.RTCRefreshButton = uibutton(app.RTCPanel, 'push');
            app.RTCRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RTCRefreshButtonPushed, true);
            app.RTCRefreshButton.Text = 'Refresh';
            app.RPDOPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.RPDOPanel.Tooltip = {''};
            app.RPDOEnabledCheckBox = uicheckbox(app.RPDOPanel);
            app.RPDOEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @RPDOEnabledCheckBoxValueChanged, true);
            app.RPDOEnabledCheckBox.Text = 'Received Data';
            app.RPDO1Label = uilabel(app.RPDOPanel);
            app.RPDO1Label.Text = 'RPDO 1';
            app.RPDO1AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO1AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO1AddrEditField.Editable = 'off';
            app.RPDO2Label = uilabel(app.RPDOPanel);
            app.RPDO2Label.Text = 'RPDO 2';
            app.RPDO2AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO2AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO2AddrEditField.Editable = 'off';
            app.RPDO3Label = uilabel(app.RPDOPanel);
            app.RPDO3Label.Text = 'RPDO 3';
            app.RPDO3AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO3AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO3AddrEditField.Editable = 'off';
            app.RPDO4Label = uilabel(app.RPDOPanel);
            app.RPDO4Label.Text = 'RPDO 4';
            app.RPDO4AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO4AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO4AddrEditField.Editable = 'off';
            app.RPDO5Label = uilabel(app.RPDOPanel);
            app.RPDO5Label.Text = 'RPDO 5';
            app.RPDO5AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO5AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO5AddrEditField.Editable = 'off';
            app.RPDO6Label = uilabel(app.RPDOPanel);
            app.RPDO6Label.Text = 'RPDO 6';
            app.RPDO6AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO6AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO6AddrEditField.Editable = 'off';
            app.RPDO7Label = uilabel(app.RPDOPanel);
            app.RPDO7Label.Text = 'RPDO 7';
            app.RPDO7AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO7AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO7AddrEditField.Editable = 'off';
            app.RPDO8Label = uilabel(app.RPDOPanel);
            app.RPDO8Label.Text = 'RPDO 8';
            app.RPDO8AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO8AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO8AddrEditField.Editable = 'off';
            app.AddressLabel = uilabel(app.RPDOPanel);
            app.AddressLabel.Text = 'Address';
            app.Label_2 = uilabel(app.RPDOPanel);
            app.Label_2.Text = '#';
            app.RPDO1SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO1SPEditField.Limits = [0 25];
            app.RPDO1SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO1SPEditFieldValueChanged, true);
            app.RPDO2SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO2SPEditField.Limits = [0 25];
            app.RPDO2SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO2SPEditFieldValueChanged, true);
            app.RPDO3SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO3SPEditField.Limits = [0 25];
            app.RPDO3SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO3SPEditFieldValueChanged, true);
            app.RPDO4SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO4SPEditField.Limits = [0 25];
            app.RPDO4SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO4SPEditFieldValueChanged, true);
            app.RPDO5SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO5SPEditField.Limits = [0 25];
            app.RPDO5SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO5SPEditFieldValueChanged, true);
            app.RPDO6SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO6SPEditField.Limits = [0 25];
            app.RPDO6SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO6SPEditFieldValueChanged, true);
            app.RPDO7SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO7SPEditField.Limits = [0 25];
            app.RPDO7SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO7SPEditFieldValueChanged, true);
            app.RPDO8SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO8SPEditField.Limits = [0 25];
            app.RPDO8SPEditField.ValueChangedFcn = createCallbackFcn(app, @RPDO8SPEditFieldValueChanged, true);
            app.RPDORefreshButton = uibutton(app.RPDOPanel, 'push');
            app.RPDORefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RPDORefreshButtonPushed, true);
            app.RPDORefreshButton.Text = 'Refresh';
            app.StartUpPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.StartUpPanel.Tooltip = {''};
            app.Label_3 = uilabel(app.StartUpPanel);
            app.Label_3.Text = '#';
            app.StartupEditField = uieditfield(app.StartUpPanel, 'numeric');
            app.StartupEditField.Limits = [0 25];
            app.StartupEnabledCheckBox = uicheckbox(app.StartUpPanel);
            app.StartupEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartupEnabledCheckBoxValueChanged, true);
            app.StartupEnabledCheckBox.Text = 'Startup';
            app.StartupRefreshButton = uibutton(app.StartUpPanel, 'push');
            app.StartupRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @StartupRefreshButtonPushed, true);
            app.StartupRefreshButton.Text = 'Refresh';
            app.OrderPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.OrderPanel.Tooltip = {''};
            app.UITable = uitable(app.OrderPanel);
            app.UITable.ColumnName = {'Current'; 'New'};
            app.UITable.ColumnEditable = [false true];
            app.WriteButton = uibutton(app.OrderPanel, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Tooltip = {'Write Script Order'};
            app.WriteButton.Text = 'Write';
            app.OrdLabel = uilabel(app.OrderPanel);
            app.OrdLabel.Text = 'Ord';
            app.SchedulerLabel = uilabel(app.OrderPanel);
            app.SchedulerLabel.Text = 'Scheduler ';
            app.ScriptPeriodEditField = uieditfield(app.OrderPanel, 'numeric');
            app.ScriptPeriodEditField.Limits = [10 10000];
            app.ScriptPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @ScriptPeriodEditFieldValueChanged, true);
            app.ScriptPeriodEditField.Tooltip = {''};
            app.ScriptPeriodEditField.Value = 100;
            app.msLabel = uilabel(app.OrderPanel);
            app.msLabel.Text = 'ms';
            app.RefreshSchedulerButton = uibutton(app.OrderPanel, 'push');
            app.RefreshSchedulerButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSchedulerButtonPushed, true);
            app.RefreshSchedulerButton.Text = 'Refresh';
            app.ScriptExecutionPeriodEditFieldLabel = uilabel(app.OrderPanel);
            app.ScriptExecutionPeriodEditFieldLabel.Text = 'Period';
            app.ScriptNumberLabel = uilabel(app.OrderPanel);
            app.ScriptNumberLabel.Text = 'Script Number';
            % Show the figure after all components are created
            app.ScriptSchedulerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptsched(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScriptSchedulerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScriptSchedulerUIFigure)
        end
    end
end