classdef bp4test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BP4AProductionTestUIFigure   matlab.ui.Figure
        PanelAccel                   matlab.ui.container.Panel
        CntEditField                 matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        ZLabel                       matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YLabel                       matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XLabel                       matlab.ui.control.Label
        PMVNETEditField              matlab.ui.control.NumericEditField
        PMVNETVEditFieldLabel        matlab.ui.control.Label
        VICEditField                 matlab.ui.control.NumericEditField
        VICADUEditFieldLabel         matlab.ui.control.Label
        VINEditField                 matlab.ui.control.NumericEditField
        VINADUEditFieldLabel         matlab.ui.control.Label
        TemperatureEditField         matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel   matlab.ui.control.Label
        VNETVINCheckButton           matlab.ui.control.StateButton
        StartButton                  matlab.ui.control.StateButton
        AccelAxes                    matlab.ui.control.UIAxes
        PanelEMG                     matlab.ui.container.Panel
        BaselineEditField            matlab.ui.control.NumericEditField
        BaselineADULabel             matlab.ui.control.Label
        CommonModeInputsignalexpectedtobe1000xDifferentialLabel  matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        ViewEMGButton                matlab.ui.control.StateButton
        ShowFFTCheckBox              matlab.ui.control.CheckBox
        CommonAmpEditField           matlab.ui.control.NumericEditField
        DifferentialAmpEditField     matlab.ui.control.NumericEditField
        VPPRTImVLabel                matlab.ui.control.Label
        GainDropDown                 matlab.ui.control.DropDown
        ChannelDropDown              matlab.ui.control.DropDown
        CMRREditField                matlab.ui.control.NumericEditField
        CMRRdBEditFieldLabel         matlab.ui.control.Label
        CommonButton                 matlab.ui.control.Button
        DifferentialButton           matlab.ui.control.Button
        EMGAxes                      matlab.ui.control.UIAxes
        BPAppRevisionEditField       matlab.ui.control.NumericEditField
        BPAppRevisionEditFieldLabel  matlab.ui.control.Label
        BPPCBSerialEditField         matlab.ui.control.NumericEditField
        BPPCBSerialEditFieldLabel    matlab.ui.control.Label
        NodeDropDown                 matlab.ui.control.DropDown
        NodeDropDownLabel            matlab.ui.control.Label
        PMAppRevisionEditField       matlab.ui.control.NumericEditField
        PMAppRevisionLabel           matlab.ui.control.Label
        PMPCBSerialEditField         matlab.ui.control.NumericEditField
        PMPCBSerialEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = public)
        nnp = []; 
        node = [];
        nSamp = [];
        plotbuf = [];
        updateTimer = [];
        emgbuf = [];
        emgTimer = [];
        emgSamp =[];
        doUpdate = false;
        pauseTime = 0.01;
    end

    properties (Access = private)
        updateCount = 1;
        savedSettings = [];
        savedTimeout =[];
        savedPMworInt=[];
        callingFigure=[]; %handle to calling figure
        lastCount=0;
    end

    methods (Access = public)
    
        function updateEMG(app, obj, event)
            resp = double(app.nnp.read(7, '2053', 3, 'uint8')); 
           
            
            %the 49th byte is a counter that increments for each packet on the PM
            %if the counter is the same as the previous counter we've read too quickly, wait until next cycle to plot
            %if the counter indicates that we missed a sample (48 data points), then pad the number of samples missed
            %with NaNs
            if length(resp) == 49
                samples = resp(49) - app.lastCount;
                if samples == 0
                    return;
                end
                if samples<0
                    samples = 256+samples; %rollover case 255 to 0 would be -255 but should be 1 sample
                end
                if samples > floor(app.emgSamp/48)
                    samples = floor(app.emgSamp/48);
                end

                if samples >= 1
                    %shift the buffer by the number of new samples (48 data points) discarding the first (oldest) points
                    app.emgbuf(1:end-48*samples) = app.emgbuf(48*samples+1:end);
                    %pad missing samples as NaNs
                    if samples > 1
                        %fprintf('\nskipped %2.0f samples', samples-1);
                        app.emgbuf(end-48*samples+1:end-48) = nan(48*(samples-1),1);
                    end
                    app.emgbuf(end-48+1:end) = resp(1:48)';
                end
                %fprintf('\n%3.0f', resp(49));
                app.lastCount=resp(49);
                app.EMGAxes.Children.YData = app.emgbuf; 
            elseif length(resp) == 1 && resp == 255
                 %fprintf('...already read')             
            else
                 %fprintf('\nradio error')             
            end
            
        end
        
        function updateDisplays(app, obj, event)

            %Get Accel
            accel=[];
            resp = app.nnp.read(app.node, '2011', 1, 'uint8');
            if length(resp)==4
                if all(resp==255)
                    accel = [Inf Inf Inf];
                    mag = Inf;
                else
                    accel = [0 0 0];
                    for i=1:3
                        accel(i) = (double((bitshift(resp(i), -2))) - 32)/16; %convert to gs for plot
                    end
                    mag = norm(accel);
                end
                app.XEditField.Value = double(resp(1));
                app.YEditField.Value = double(resp(2));
                app.ZEditField.Value = double(resp(3));
                app.CntEditField.Value = double(resp(4));
            end

            if ~isempty(accel) 
                app.plotbuf(1:end-1,:) = app.plotbuf(2:end,:);
                app.plotbuf(end,:) = [accel'; mag];
                for i = 1:4
                    app.AccelAxes.Children(i).YData = app.plotbuf(:,i); 
                    
                end              
            end

                    
            drawnow;
            %pause(app.pauseTime);
            
            switch app.updateCount
                case 1
                    
                    %Get Temp
                    resp = app.nnp.getTemp(app.node);
                    %fprintf('\ntemp %f', resp);
                    if length(resp)==1
                        app.TemperatureEditField.Value = resp;
                        app.TemperatureEditField.BackgroundColor = [1 1 1];
                    else
                        app.TemperatureEditField.Value = Inf;
                        app.TemperatureEditField.BackgroundColor = [.5 .5 .5];
                    end
                    
                case 2  
                    
                     %Get Diagnostics VIC
                    resp = double(app.nnp.read(app.node, '3410', 2, 'uint8'));  %VIC
                    %fprintf('\ndiagnostics %f', resp);
                    if length(resp) == 1
                        app.VICEditField.Value = resp;
                        app.VICEditField.BackgroundColor = [1 1 1];
                    else
                        app.VICEditField.Value = Inf;
                        app.VICEditField.BackgroundColor = [.5 .5 .5];
                    end
                case 3
                    
                    %Get Diagnostics VIN
                    resp = double(app.nnp.read(app.node, '3510', 2, 'uint8')); %VIN
                    %fprintf('\ndiagnostics %f', resp);
                     if length(resp) == 1
                        app.VINEditField.Value = resp;
                        app.VINEditField.BackgroundColor = [1 1 1];
                    else
                        app.VINEditField.Value = Inf;
                        app.VINEditField.BackgroundColor = [.5 .5 .5];
                     end
                
            end
            app.updateCount = app.updateCount+1;
            if app.updateCount == 4
                app.updateCount = 1;
            end
            
            if app.doUpdate
               enable = 'on';
            else
               enable = 'off';
            end
            
           
            app.PMVNETEditField.Enable = enable;
            app.PMPCBSerialEditField.Enable = 'off';   
            app.PMAppRevisionEditField.Enable = 'off'; 
            app.BPPCBSerialEditField.Enable = 'off'; 
            app.BPAppRevisionEditField.Enable = 'off'; 

            
            if ~app.doUpdate            
                return
            end
            
            %pause(app.pauseTime);
            
            %Get PM VNET setting
            resp = app.nnp.getVNET();
            %fprintf('\nvnet %f', resp);
            if length(resp)==1
                app.PMVNETEditField.Value = resp;
                app.PMVNETEditField.BackgroundColor = [1 1 1];
            else
                app.PMVNETEditField.Value = Inf;
                app.PMVNETEditField.BackgroundColor = [.5 .5 .5];
                
            end

            app.doUpdate = false;
        end
        
    end

    methods (Access = private)
    
        function restoreRadio(app)
            app.nnp.setRadioSettings(app.savedSettings);
            app.nnp.timeout = app.savedTimeout;
            if app.savedPMworInt > 0
                app.nnp.worOn(app.savedPMworInt); %reenable WOR on PM if it was enabled
            end
        end
        
        function saveRadio(app)
            %read back AP settings
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                uialert(app.BP4AProductionTestUIFigure, "Could not read back AccessPoint Settings", "Radio Configuration");
                return;
            else
                app.savedSettings = settings;
            end
            app.savedTimeout = app.nnp.timeout;
            
             %is PM WOR enabled?
             resp = double(app.nnp.read(7, '2600', 9, 'uint16'));
             if length(resp)==1 
                 app.savedPMworInt = resp;
             else
                 uialert(app.BP4AProductionTestUIFigure, "Could not determine if PM has WOR enabled, will get set to default 20ms", "");
                 app.savedPMworInt = 20;
             end
        end
        
        function startEMG(app)
            app.enableAll('off');
            app.saveRadio();
            %for max bandwidth, we need to eliminate radio retries, shorten timeouts, and turn of WOR
            app.nnp.setRadio('timeout', 10, 'retries', 0');  
            app.nnp.worOff();
            app.nnp.timeout = 0.05;%4;
            
            app.EMGAxes.Color = [1 1 1];
            
            %Set diagnostic Mode and channel
            app.ChannelDropDown.Enable = 'off';
            app.GainDropDown.Enable = 'off';
             switch str2double(app.ChannelDropDown.Value)
                 case 1
                    ch=1;
                    mode=0;
                 case 2
                    ch=2;
                    mode=0;
                 case 3
                    ch=3;
                    mode=0;
                 case 4
                    ch=4;
                    mode=0;
                 case 11
                    ch=1;
                    mode=2;
                 case 12
                    ch=2;
                    mode=2;
                 case 13
                    ch=3;
                    mode=2;
                 case 14
                    ch=4;
                    mode=2;
                 otherwise
                     error('unknown dropdown value')
             end

            if app.nnp.write(app.node, '3000', 1, mode, 'uint8')~=0
                uialert(app.BP4AProductionTestUIFigure, ['Enter diagnostic mode ', num2str(mode), ' may have failed'], "");
            end
            
            if ~app.nnp.enterTestRaw(app.node, ch)
                uialert(app.BP4AProductionTestUIFigure, "Could not enter Raw MES mode", "");
            end
            
            start(app.emgTimer);
        end
        
        function stopEMG(app)
            app.enableAll('on');
            stop(app.emgTimer);
            app.ChannelDropDown.Enable = 'on';
            app.GainDropDown.Enable = 'on';
            app.restoreRadio();
            if ~app.nnp.enterWaiting()
                uialert(app.BP4AProductionTestUIFigure, "Could not enter waiting mode", "");
            end
        end
        
        function [amp, freq, baseline, sat] = calcFFT(app)
            Fs = 1000;            % Sampling frequency                    
            T = 1/Fs;             % Sampling period       
            L = app.emgSamp;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            r = 3;                % frequency tolerance around max freq (in Hz)
            
            good = ~isnan(app.emgbuf);
            
            if all(~good)
                uialert(app.BP4AProductionTestUIFigure, "No EMG Data", "Data Warning");
                amp = Inf;
                freq = Inf;
                baseline = Inf;
                sat = true;
                return;
            elseif any(~good)
                igood = find(diff([0; good; 0])==-1);  %transition from good to bad
                ibad = find(diff([0; good; 0])==1);  %transition from bad to good

                [L, j] = max(igood - ibad); %new Length of signal

                i = igood(j) - L; %starting index in app.emgbuf of longest non-nan section
                x = app.emgbuf(i:i+L-1);
                uialert(app.BP4AProductionTestUIFigure, sprintf('Empty sections were found in EMG data.\n\nCalculating outputs based on %u samples instead of %u',L, app.emgSamp), "Data Warning");
            else 
                 x=app.emgbuf;
            end
            Y = fft(x);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);

            baseline = round(P1(1));

            [~, freq] = max(P1(2:end));
            
            if freq > r
                ampRaw = sum(P1(freq-r+1:freq+r+1));  %use max freq+/-3Hz
            else
                ampRaw = 0;
            end
            
            if ampRaw > 255
                sat = true;
                app.EMGAxes.Color = [1 .7 .7];
            else
                sat = false;
            end
                
            switch str2double(app.GainDropDown.Value)
                case 0, gain=48;
                case 38, gain=300;
                case 191, gain=1500;
                case 255, gain=2040;
    
                otherwise, uialert(app.BP4AProductionTestUIFigure, "gain", "");
            end
            amp = ampRaw*3.3/256/gain*1000; %amplitude as Peak to Peak in mV


            if app.ShowFFTCheckBox.Value 
                f = Fs*(0:(L/2))/L;
                figure; 
                plot(f, P1); 
                title('Single-Sided Amplitude Spectrum of EMG')
                xlabel('f (Hz)')
                ylabel('EMG Power (ADU)')
            end
        end
        
        function calcCMRR(app)
            if isfinite(app.DifferentialAmpEditField.Value) && isfinite(app.CommonAmpEditField.Value)
                app.CMRREditField.Value = 20*log10(app.DifferentialAmpEditField.Value/(app.CommonAmpEditField.Value/1000));
            end
        end
        
        function enableAll(app, enable)
            app.ViewEMGButton.Enable = enable;
            app.StartButton.Enable = enable;
            app.DifferentialButton.Enable = enable;
            app.CommonButton.Enable = enable;
            app.NodeDropDown.Enable = enable;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    uialert(app.BP4AProductionTestUIFigure, "No port chosen for NNP Access Point", "");
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end


            app.PMPCBSerialEditField.Enable = 'on';  
            app.PMAppRevisionEditField.Enable = 'on'; 
            
            %Get PM App Revs
            resp = app.nnp.getSwRev(7); 
            if length(resp)==1
                app.PMAppRevisionEditField.Value = resp(1);
                app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PMAppRevisionEditField.Value = Inf;
                app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get PM Serial number
            resp = app.nnp.getSerial(7);  
            if length(resp)==1
                app.PMPCBSerialEditField.Value = resp(1);
                app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PMPCBSerialEditField.Value = Inf; 
                app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.enterWaiting()
                uialert(app.BP4AProductionTestUIFigure, "Could not enter waiting on PM", "");
                return;
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.setVNET(7.5)
                uialert(app.BP4AProductionTestUIFigure, "Could not set PM VNET", "");
                return;
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.networkOn()
                uialert(app.BP4AProductionTestUIFigure, "Could not turn on network", "");
                return;
            end
            
            pause(0.2)
            while app.nnp.getNetworkStatus()~=1
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
            end
            
            app.VNETVINCheckButton.Enable = 'off';    
            
            app.nSamp = 100;
            app.plotbuf = zeros(app.nSamp,4);
            plot(app.AccelAxes, 1:app.nSamp, app.plotbuf);
            app.AccelAxes.XLim = [0 app.nSamp];
            app.AccelAxes.YLim = [-2 2];
            app.AccelAxes.Children(1).Color = [1 0 0]; %X=red
            app.AccelAxes.Children(2).Color = [0 .5 0];%Y=green
            app.AccelAxes.Children(3).Color = [0 0 1]; %Z=blue
            app.AccelAxes.Children(4).Color = [0 0 0]; %Mag=black, dotted
            app.AccelAxes.Children(4).LineStyle =':'; 
            
            app.emgSamp = 1000;
            app.emgbuf = nan(app.emgSamp, 1);
            plot(app.EMGAxes, 1:app.emgSamp, app.emgbuf);
            app.EMGAxes.XLim = [0 1000];
            app.EMGAxes.YLim = [0 255];

            app.EMGAxes.XTick = 40:48:1000;
            app.EMGAxes.XMinorTick = 'on';
            app.EMGAxes.XAxis.MinorTickValues = 0:8:1000;
            app.EMGAxes.XGrid = 'on';
            app.EMGAxes.XMinorGrid = 'on';
            
            app.EMGAxes.YTick = 0:25:255;
            app.EMGAxes.YGrid = 'on';
            
            pause(0.1);
            app.NodeDropDownValueChanged();
            
            app.updateTimer = timer();
            app.updateTimer.Period = 0.25;
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.BusyMode = 'drop';
            app.updateTimer.TimerFcn = @app.updateDisplays;
            
            
            app.emgTimer = timer();
            app.emgTimer.Period = 0.048;
            app.emgTimer.ExecutionMode = 'fixedRate';
            app.emgTimer.BusyMode = 'drop';
            app.emgTimer.TimerFcn = @app.updateEMG;

            
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            app.node = str2double(app.NodeDropDown.Value);
            
            
            app.BPPCBSerialEditField.Enable = 'on'; 
            app.BPAppRevisionEditField.Enable = 'on'; 
            
            %Check module type
            resp = app.nnp.getType(app.node);
            if ~isempty(resp) && (~isequal(resp, '2ch Biopotential Sensor BP-2') && ~isequal(resp, '4ch Biopotential Sensor BP-4'))
                uialert(app.BP4AProductionTestUIFigure, "Connected device is not a BP", "BP Test");
            end
            
            %Get RM App Revs
            pause(0.1)
            resp = app.nnp.getSwRev(app.node); 
            if length(resp)==1
                app.BPAppRevisionEditField.Value = resp;
                app.BPAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.BPAppRevisionEditField.Value = Inf;
                app.BPAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get RM Serial number
            resp = app.nnp.getSerial(app.node);  
            if length(resp)==1
                app.BPPCBSerialEditField.Value = resp;
                app.BPPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.BPPCBSerialEditField.Value = Inf; 
                app.BPPCBSerialEditField.BackgroundColor = [1 0 0];
            end


            %pause(app.pauseTime);
            

        end

        % Close request function: BP4AProductionTestUIFigure
        function BP4AProductionTestUIFigureCloseRequest(app, event)
            if ~isempty(app.updateTimer)
                if isvalid(app.updateTimer)
                    stop(app.updateTimer);
                end
                delete(app.updateTimer);
            end
            if ~isempty(app.emgTimer)
                if isvalid(app.emgTimer)
                    stop(app.emgTimer);
                end
                delete(app.emgTimer);
            end
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end

            delete(app);
        end

        % Callback function
        function reinitializeButtonPushed(app, event)
            delete(app.nnp);
            app.startupFcn();
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
           
            
            %Set diagnostic Mode
            if app.nnp.write(app.node, '3000', 1, 1, 'uint8')~=0 
                uialert(app.BP4AProductionTestUIFigure, "Enter diagnostic mode 1 may have failed", "");
            end
            
            if app.StartButton.Value == 1 
                app.enableAll('off');
                app.StartButton.Enable = 'on';
                if ~isempty(app.updateTimer)
                    app.StartButton.Text = 'Running. Press to Stop';
                    drawnow;

                    app.doUpdate = true;
                    start(app.updateTimer)
                    app.VNETVINCheckButton.Enable = 'on';
                    return;
                else
                    uialert(app.BP4AProductionTestUIFigure, "No timer to start", "");
                end
            else
                app.enableAll('on');
               if ~isempty(app.updateTimer)
                    stop(app.updateTimer)
               else
                   uialert(app.BP4AProductionTestUIFigure, "No timer to stop", "");
               end
            end
            pause(0.5);
            app.StartButton.Text = 'Start';
            app.VNETVINCheckButton.Enable = 'off';
            drawnow;
            pause(1);
            
            %set gain to default
            % if app.nnp.setBPGains(app.node, 11, 11)
            %     [ch1, ch2] = app.nnp.getBPGains(app.node);
            %     if isempty(ch1) || isempty(ch2) || ch1 ~= ch2 || ch1 ~= 11 
            %         msgbox(['Gains not verified:', app.nnp.lastError])
            %     end
            % else
            %      msgbox(['Gains not set:', app.nnp.lastError])
            % end

            app.StartButton.Value = 0;
            
            
        end

        % Value changed function: VNETVINCheckButton
        function VNETVINCheckButtonValueChanged(app, event)
            if app.VNETVINCheckButton.Value
                if ~app.nnp.setVNET(8.5)
                    uialert(app.BP4AProductionTestUIFigure, "Action may have failed", "");
                end
            else
                if ~app.nnp.setVNET(7.5)
                    uialert(app.BP4AProductionTestUIFigure, "Action may have failed", "");
                end
            end
            app.doUpdate = true;
        end

        % Value changed function: ViewEMGButton
        function ViewEMGButtonValueChanged(app, event)
           if app.ViewEMGButton.Value
                %read and ignore data that may still be in transfer buffer
                resp = double(app.nnp.read(7, '2053', 3, 'uint8')); 
                app.emgbuf = nan(app.emgSamp, 1);
                app.EMGAxes.Children.YData = app.emgbuf; 

                app.startEMG();
                app.ViewEMGButton.Enable = 'on';
                app.GainDropDown.Enable = 'on'; % allow gain changes while viewing
            else
                app.stopEMG();
            end
        end

        % Value changed function: GainDropDown
        function GainDropDownValueChanged(app, event)
            gain = str2double(app.GainDropDown.Value);
            ch = str2double(app.ChannelDropDown.Value);

            if(ch > 4)
                return;
            end

            if ~app.nnp.setBPGains(app.node, ch, gain)
                uialert(app.BP4AProductionTestUIFigure, "Gains not set", "");
            end

        end

        % Button pushed function: DifferentialButton
        function DifferentialButtonPushed(app, event)
            app.DifferentialButton.BackgroundColor = [0 1 0];
            app.startEMG();
            pause(1.5)
            app.stopEMG();
            app.DifferentialButton.BackgroundColor = [.96 .96 .96];
            [amp, freq, baseline] = app.calcFFT();
            app.DifferentialAmpEditField.Value = amp;
            app.FrequencyEditField.Value = freq;
            app.BaselineEditField.Value = baseline;
            calcCMRR(app);
        end

        % Button pushed function: CommonButton
        function CommonButtonPushed(app, event)
            app.CommonButton.BackgroundColor = [0 1 0];
            app.startEMG();
            pause(1.5)
            app.stopEMG();
            app.CommonButton.BackgroundColor = [.96 .96 .96];
            [amp, freq, baseline] = app.calcFFT();
            app.CommonAmpEditField.Value = amp;
            app.FrequencyEditField.Value = freq;
            app.BaselineEditField.Value = baseline;
            calcCMRR(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BP4AProductionTestUIFigure = uifigure('Visible', 'off');
            app.BP4AProductionTestUIFigure.Name = 'BP4A Production Test';
            app.BP4AProductionTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @BP4AProductionTestUIFigureCloseRequest, true);
            app.PMPCBSerialEditFieldLabel = uilabel(app.BP4AProductionTestUIFigure);
            app.PMPCBSerialEditFieldLabel.Text = 'PM PCB Serial #';
            app.PMPCBSerialEditField = uieditfield(app.BP4AProductionTestUIFigure, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.PMAppRevisionLabel = uilabel(app.BP4AProductionTestUIFigure);
            app.PMAppRevisionLabel.Text = 'PM App Revision';
            app.PMAppRevisionEditField = uieditfield(app.BP4AProductionTestUIFigure, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.NodeDropDownLabel = uilabel(app.BP4AProductionTestUIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.BP4AProductionTestUIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '9', '10'};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Value = '9';
            app.BPPCBSerialEditFieldLabel = uilabel(app.BP4AProductionTestUIFigure);
            app.BPPCBSerialEditFieldLabel.Text = 'BP PCB Serial #';
            app.BPPCBSerialEditField = uieditfield(app.BP4AProductionTestUIFigure, 'numeric');
            app.BPPCBSerialEditField.Editable = 'off';
            app.BPPCBSerialEditField.Value = Inf;
            app.BPAppRevisionEditFieldLabel = uilabel(app.BP4AProductionTestUIFigure);
            app.BPAppRevisionEditFieldLabel.Text = 'BP App Revision';
            app.BPAppRevisionEditField = uieditfield(app.BP4AProductionTestUIFigure, 'numeric');
            app.BPAppRevisionEditField.Editable = 'off';
            app.BPAppRevisionEditField.Value = Inf;
            app.PanelEMG = uipanel(app.BP4AProductionTestUIFigure);
            app.EMGAxes = uiaxes(app.PanelEMG);
            title(app.EMGAxes, 'Raw EMG')
            xlabel(app.EMGAxes, 'time (ms)')
            ylabel(app.EMGAxes, 'EMG (ADU)')
            app.DifferentialButton = uibutton(app.PanelEMG, 'push');
            app.DifferentialButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentialButtonPushed, true);
            app.DifferentialButton.Text = 'Differential';
            app.CommonButton = uibutton(app.PanelEMG, 'push');
            app.CommonButton.ButtonPushedFcn = createCallbackFcn(app, @CommonButtonPushed, true);
            app.CommonButton.Text = 'Common';
            app.CMRRdBEditFieldLabel = uilabel(app.PanelEMG);
            app.CMRRdBEditFieldLabel.Text = 'CMRR (dB)';
            app.CMRREditField = uieditfield(app.PanelEMG, 'numeric');
            app.CMRREditField.Editable = 'off';
            app.CMRREditField.Value = Inf;
            app.ChannelDropDown = uidropdown(app.PanelEMG);
            app.ChannelDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'Offset 1', 'Offset 2', 'Offset 3', 'Offset 4'};
            app.ChannelDropDown.ItemsData = {'1', '2', '3', '4', '11', '12', '13', '14'};
            app.ChannelDropDown.Value = '1';
            app.GainDropDown = uidropdown(app.PanelEMG);
            app.GainDropDown.Items = {'Gain 48x', 'Gain 300x', 'Gain 1500x', 'Gain 2040x'};
            app.GainDropDown.ItemsData = {'0', '38', '191', '255'};
            app.GainDropDown.ValueChangedFcn = createCallbackFcn(app, @GainDropDownValueChanged, true);
            app.GainDropDown.Value = '0';
            app.VPPRTImVLabel = uilabel(app.PanelEMG);
            app.VPPRTImVLabel.Text = 'VPP RTI (mV)';
            app.DifferentialAmpEditField = uieditfield(app.PanelEMG, 'numeric');
            app.DifferentialAmpEditField.Editable = 'off';
            app.DifferentialAmpEditField.Value = Inf;
            app.CommonAmpEditField = uieditfield(app.PanelEMG, 'numeric');
            app.CommonAmpEditField.Editable = 'off';
            app.CommonAmpEditField.Value = Inf;
            app.ShowFFTCheckBox = uicheckbox(app.PanelEMG);
            app.ShowFFTCheckBox.Text = 'Show FFT';
            app.ViewEMGButton = uibutton(app.PanelEMG, 'state');
            app.ViewEMGButton.ValueChangedFcn = createCallbackFcn(app, @ViewEMGButtonValueChanged, true);
            app.ViewEMGButton.Text = 'View EMG';
            app.FrequencyHzEditFieldLabel = uilabel(app.PanelEMG);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyEditField = uieditfield(app.PanelEMG, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyEditField.Value = Inf;
            app.CommonModeInputsignalexpectedtobe1000xDifferentialLabel = uilabel(app.PanelEMG);
            app.CommonModeInputsignalexpectedtobe1000xDifferentialLabel.Text = 'Common Mode Input signal expected to be 1000x Differential Input Signal';
            app.BaselineADULabel = uilabel(app.PanelEMG);
            app.BaselineADULabel.Text = 'Baseline (ADU)';
            app.BaselineEditField = uieditfield(app.PanelEMG, 'numeric');
            app.BaselineEditField.Editable = 'off';
            app.BaselineEditField.Value = Inf;
            app.PanelAccel = uipanel(app.BP4AProductionTestUIFigure);
            app.AccelAxes = uiaxes(app.PanelAccel);
            title(app.AccelAxes, 'Accelerometer')
            xlabel(app.AccelAxes, 'sample')
            ylabel(app.AccelAxes, 'acceleration (g''s)')
            app.StartButton = uibutton(app.PanelAccel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.VNETVINCheckButton = uibutton(app.PanelAccel, 'state');
            app.VNETVINCheckButton.ValueChangedFcn = createCallbackFcn(app, @VNETVINCheckButtonValueChanged, true);
            app.VNETVINCheckButton.Text = 'VNET/VIN Check';
            app.TemperatureCEditFieldLabel = uilabel(app.PanelAccel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.TemperatureEditField = uieditfield(app.PanelAccel, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.TemperatureEditField.Value = Inf;
            app.VINADUEditFieldLabel = uilabel(app.PanelAccel);
            app.VINADUEditFieldLabel.Text = 'VIN (ADU)';
            app.VINEditField = uieditfield(app.PanelAccel, 'numeric');
            app.VINEditField.Editable = 'off';
            app.VINEditField.Value = Inf;
            app.VICADUEditFieldLabel = uilabel(app.PanelAccel);
            app.VICADUEditFieldLabel.Text = 'VIC (ADU)';
            app.VICEditField = uieditfield(app.PanelAccel, 'numeric');
            app.VICEditField.Editable = 'off';
            app.VICEditField.Value = Inf;
            app.PMVNETVEditFieldLabel = uilabel(app.PanelAccel);
            app.PMVNETVEditFieldLabel.Text = 'PM VNET (V)';
            app.PMVNETEditField = uieditfield(app.PanelAccel, 'numeric');
            app.PMVNETEditField.Editable = 'off';
            app.PMVNETEditField.Value = Inf;
            app.XLabel = uilabel(app.PanelAccel);
            app.XLabel.Text = 'X';
            app.XEditField = uieditfield(app.PanelAccel, 'numeric');
            app.XEditField.Editable = 'off';
            app.XEditField.Value = Inf;
            app.YLabel = uilabel(app.PanelAccel);
            app.YLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PanelAccel, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Value = Inf;
            app.ZLabel = uilabel(app.PanelAccel);
            app.ZLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PanelAccel, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Value = Inf;
            app.EditFieldLabel = uilabel(app.PanelAccel);
            app.EditFieldLabel.Text = '#';
            app.CntEditField = uieditfield(app.PanelAccel, 'numeric');
            app.CntEditField.Editable = 'off';
            app.CntEditField.Value = Inf;
            % Show the figure after all components are created
            app.BP4AProductionTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bp4test(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BP4AProductionTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BP4AProductionTestUIFigure)
        end
    end
end