classdef pgtest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PG4DProductionTestUIFigure  matlab.ui.Figure
        ChannelLabel                matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        VNETVINCheckButton          matlab.ui.control.StateButton
        CntEditField                matlab.ui.control.NumericEditField
        EditFieldLabel              matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZLabel                      matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YLabel                      matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XLabel                      matlab.ui.control.Label
        StartButton                 matlab.ui.control.StateButton
        C4EditField                 matlab.ui.control.NumericEditField
        C3EditField                 matlab.ui.control.NumericEditField
        C2EditField                 matlab.ui.control.NumericEditField
        C1EditField                 matlab.ui.control.NumericEditField
        complianceLabel             matlab.ui.control.Label
        PW4EditField                matlab.ui.control.NumericEditField
        PA4EditField                matlab.ui.control.NumericEditField
        PW3EditField                matlab.ui.control.NumericEditField
        PA3EditField                matlab.ui.control.NumericEditField
        PA2EditField                matlab.ui.control.NumericEditField
        PW2EditField                matlab.ui.control.NumericEditField
        PMVNETEditField             matlab.ui.control.NumericEditField
        PMVNETVEditFieldLabel       matlab.ui.control.Label
        PGAppRevisionEditField      matlab.ui.control.NumericEditField
        PGAppRevisionLabel          matlab.ui.control.Label
        PGPCBSerialEditField        matlab.ui.control.NumericEditField
        PGPCBSerialEditFieldLabel   matlab.ui.control.Label
        NodeDropDown                matlab.ui.control.DropDown
        NodeDropDownLabel           matlab.ui.control.Label
        VOSEditField                matlab.ui.control.NumericEditField
        VOSVEditFieldLabel          matlab.ui.control.Label
        TEditField                  matlab.ui.control.NumericEditField
        TmsEditFieldLabel           matlab.ui.control.Label
        PA1EditField                matlab.ui.control.NumericEditField
        PAmAEditFieldLabel          matlab.ui.control.Label
        PW1EditField                matlab.ui.control.NumericEditField
        PWusEditFieldLabel          matlab.ui.control.Label
        StimButton                  matlab.ui.control.StateButton
        VICEditField                matlab.ui.control.NumericEditField
        VICADUEditFieldLabel        matlab.ui.control.Label
        VINEditField                matlab.ui.control.NumericEditField
        VINADUEditFieldLabel        matlab.ui.control.Label
        V3EditField                 matlab.ui.control.NumericEditField
        V3ADUEditFieldLabel         matlab.ui.control.Label
        VOSCheckButton              matlab.ui.control.StateButton
        PMAppRevisionEditField      matlab.ui.control.NumericEditField
        PMAppRevisionLabel          matlab.ui.control.Label
        PMPCBSerialEditField        matlab.ui.control.NumericEditField
        PMPCBSerialEditFieldLabel   matlab.ui.control.Label
        TemperatureEditField        matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel  matlab.ui.control.Label
        AccelAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        nnp = []; 
        node = [];
        nSamp = [];
        plotbuf = [];
        updateTimer = [];
        doUpdate = false;
        pauseTime = 0.01;
    end

    properties (Access = private)
        updateCount = 1;
    end

    methods (Access = public)
    
        function updateDisplays(app, obj, event)

            %Get Accel
            accel=[];
            resp = app.nnp.read(app.node, '2011', 1, 'uint8');
            if length(resp)==4
                if all(resp==255)
                    accel = [Inf Inf Inf];
                    mag = Inf;
                else
                    accel = [0 0 0];
                    for i=1:3
                        
                            %accel(i)=double(typecast(resp(i), 'int8'))*2/128;
                        
                            accel(i) = (double((bitshift(resp(i), -2))) - 32)/16; %convert to gs for plot
                      
                    end
                    mag = norm(accel);
                end
                app.XEditField.Value = double(resp(1));
                app.YEditField.Value = double(resp(2));
                app.ZEditField.Value = double(resp(3));
                app.CntEditField.Value = double(resp(4));
            end

            if ~isempty(accel) 
                app.plotbuf(1:end-1,:) = app.plotbuf(2:end,:);
                app.plotbuf(end,:) = [accel'; mag];
                for i = 1:4
                    app.AccelAxes.Children(i).YData = app.plotbuf(:,i); 
                    
                end              
            end

                    
            drawnow;
            %pause(app.pauseTime);
            
            switch app.updateCount
                case 1
                    
                    %Get Temp
                    resp = app.nnp.getTemp(app.node);
                    %fprintf('\ntemp %f', resp);
                    if length(resp)==1
                        app.TemperatureEditField.Value = resp;
                        app.TemperatureEditField.BackgroundColor = [1 1 1];
                    else
                        app.TemperatureEditField.Value = Inf;
                        app.TemperatureEditField.BackgroundColor = [.5 .5 .5];
                    end
                    
                case 2  
                    
                     %Get Diagnostics
                    resp = double(app.nnp.read(app.node, '3000', 2, 4, 'uint8')); 
                    %fprintf('\ndiagnostics %f', resp);
                    if length(resp) == 4
                        app.VINEditField.Value = resp(1);
                        app.VICEditField.Value = resp(2);
                        app.V3EditField.Value = resp(4);
                        app.VINEditField.BackgroundColor = [1 1 1];
                        app.VICEditField.BackgroundColor = [1 1 1];
                        app.V3EditField.BackgroundColor = [1 1 1];
                    else
                        app.VINEditField.Value = Inf;
                        app.VICEditField.Value = Inf;
                        app.V3EditField.Value = Inf;
                        app.VINEditField.BackgroundColor = [.5 .5 .5];
                        app.VICEditField.BackgroundColor = [.5 .5 .5];
                        app.V3EditField.BackgroundColor = [.5 .5 .5];
                    end
            
                case 3
           
                    %Compliance Check
                    resp = double(app.nnp.read(app.node, '3210', 5, 'uint8'));
                    %fprintf('\ncompliance %f', resp);
                    if length(resp)==4
                        app.C1EditField.Value = resp(1);
                        app.C2EditField.Value = resp(2);
                        app.C3EditField.Value = resp(3);
                        app.C4EditField.Value = resp(4);
                        app.C1EditField.BackgroundColor = [1 1 1];
                        app.C2EditField.BackgroundColor = [1 1 1];
                        app.C3EditField.BackgroundColor = [1 1 1];
                        app.C4EditField.BackgroundColor = [1 1 1];
                    else
                        app.C1EditField.Value = Inf;
                        app.C2EditField.Value = Inf;
                        app.C3EditField.Value = Inf;
                        app.C4EditField.Value = Inf;
                        app.C1EditField.BackgroundColor = [.5 .5 .5];
                        app.C2EditField.BackgroundColor = [.5 .5 .5];
                        app.C3EditField.BackgroundColor = [.5 .5 .5];
                        app.C4EditField.BackgroundColor = [.5 .5 .5];
                    end
            end
            app.updateCount = app.updateCount+1;
            if app.updateCount == 4
                app.updateCount = 1;
            end
            
            if app.doUpdate
               enable = 'on';
            else
               enable = 'off';
            end
            
            app.PW1EditField.Enable = enable;
            app.PW1EditField.Enable = enable;
            app.PA1EditField.Enable = enable;
            app.PW2EditField.Enable = enable;
            app.PA2EditField.Enable = enable;
            app.PW3EditField.Enable = enable;
            app.PA3EditField.Enable = enable;
            app.PW4EditField.Enable = enable;
            app.PA4EditField.Enable = enable;
            app.PMVNETEditField.Enable = enable;
            app.TEditField.Enable = enable;
            app.VOSEditField.Enable = enable;
            app.PMPCBSerialEditField.Enable = 'off';   
            app.PMAppRevisionEditField.Enable = 'off'; 
            app.PGPCBSerialEditField.Enable = 'off'; 
            app.PGAppRevisionEditField.Enable = 'off'; 

            
            if ~app.doUpdate            
                return
            end
            
            %pause(app.pauseTime);
            
            %Get PM VNET setting
            resp = app.nnp.getVNET();
            %fprintf('\nvnet %f', resp);
            if length(resp)==1
                app.PMVNETEditField.Value = resp;
                app.PMVNETEditField.BackgroundColor = [1 1 1];
            else
                app.PMVNETEditField.Value = Inf;
                app.PMVNETEditField.BackgroundColor = [.5 .5 .5];
                
            end
            
            %pause(app.pauseTime);
            
            %Get sync period
            resp = app.nnp.getSync(); 
            %fprintf('\nsync %f', resp);
            if length(resp)==1
                app.TEditField.Value = resp;
                app.TEditField.BackgroundColor = [1 1 1];
            else
                app.TEditField.Value = Inf;
                app.TEditField.BackgroundColor = [.5 .5 .5];
                
            end
            
            %pause(app.pauseTime);
            
            %Get Stim VOS
            resp = double(app.nnp.read(app.node, '3210', 3, 'uint16'));
            %fprintf('\nvos %f', resp);
            if length(resp)==1
                app.VOSEditField.Value = resp/60;
                app.VOSEditField.BackgroundColor = [1 1 1];
            else
                app.VOSEditField.Value = Inf;
                app.VOSEditField.BackgroundColor = [.5 .5 .5];
            end
            
            %pause(app.pauseTime);
            
            %Stim ch1 Check
            resp = double(app.nnp.read(app.node, '3212', 1, 'uint8'));
            %fprintf('\nstim1 %f', resp);
            if length(resp)==2
                app.PW1EditField.Value = resp(1);
                app.PA1EditField.Value = resp(2)/10;
                app.PW1EditField.BackgroundColor = [1 1 1];
                app.PA1EditField.BackgroundColor = [1 1 1];
            else
                app.PW1EditField.Value = Inf;
                app.PA1EditField.Value = Inf;
                app.PW1EditField.BackgroundColor = [.5 .5 .5];
                app.PA1EditField.BackgroundColor = [.5 .5 .5];
            end
            
            %pause(app.pauseTime);
            
            %Stim ch2 Check
            resp = double(app.nnp.read(app.node, '3212', 2, 'uint8'));
            %fprintf('\nstim2 %f', resp);
            if length(resp)==2
                app.PW2EditField.Value = resp(1);
                app.PA2EditField.Value = resp(2)/10;
                app.PW2EditField.BackgroundColor = [1 1 1];
                app.PA2EditField.BackgroundColor = [1 1 1];
            else
                app.PW2EditField.Value = Inf;
                app.PA2EditField.Value = Inf;
                app.PW2EditField.BackgroundColor = [.5 .5 .5];
                app.PA2EditField.BackgroundColor = [.5 .5 .5];
            end
            
            %pause(app.pauseTime);
            
            %Stim ch3 Check
            resp = double(app.nnp.read(app.node, '3212', 3, 'uint8'));
            %fprintf('\nstim3 %f', resp);
            if length(resp)==2
                app.PW3EditField.Value = resp(1);
                app.PA3EditField.Value = resp(2)/10;
                app.PW3EditField.BackgroundColor = [1 1 1];
                app.PA3EditField.BackgroundColor = [1 1 1];
            else
                app.PW3EditField.Value = Inf;
                app.PA3EditField.Value = Inf;
                app.PW3EditField.BackgroundColor = [.5 .5 .5];
                app.PA3EditField.BackgroundColor = [.5 .5 .5];
            end
            
            %pause(app.pauseTime);
            
             %Stim ch4 Check
            resp = double(app.nnp.read(app.node, '3212', 4, 'uint8'));
            %fprintf('\nstim4 %f', resp);
            if length(resp)==2
                app.PW4EditField.Value = resp(1);
                app.PA4EditField.Value = resp(2)/10;
                app.PW4EditField.BackgroundColor = [1 1 1];
                app.PA4EditField.BackgroundColor = [1 1 1];
            else
                app.PW4EditField.Value = Inf;
                app.PA4EditField.Value = Inf;
                app.PW4EditField.BackgroundColor = [.5 .5 .5];
                app.PA4EditField.BackgroundColor = [.5 .5 .5];
            end
            
            app.doUpdate = false;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            
            app.PMPCBSerialEditField.Enable = 'on';  
            app.PMAppRevisionEditField.Enable = 'on'; 
            
            %Get PM App Revs
            resp = app.nnp.getSwRev(7); 
            if length(resp)==1
                app.PMAppRevisionEditField.Value = resp(1);
                app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PMAppRevisionEditField.Value = Inf;
                app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get PM Serial number
            resp = app.nnp.getSerial(7);  
            if length(resp)==1
                app.PMPCBSerialEditField.Value = resp(1);
                app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PMPCBSerialEditField.Value = Inf; 
                app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM');
                return;
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.setVNET(7.5)
                msgbox('could not set PM VNET');
                return;
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.networkOn()
                msgbox('could not turn on network');
                return;
            end
            
            pause(0.2)
            while app.nnp.getNetworkStatus()~=1
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
            end


            app.nSamp = 100;
            app.plotbuf = zeros(app.nSamp,4);
            plot(app.AccelAxes, 1:app.nSamp, app.plotbuf);
            app.AccelAxes.XLim = [0 app.nSamp];
            app.AccelAxes.YLim = [-2 2];
            app.AccelAxes.Children(1).Color = [1 0 0]; %X=red
            app.AccelAxes.Children(2).Color = [0 .5 0];%Y=green
            app.AccelAxes.Children(3).Color = [0 0 1]; %Z=blue
            app.AccelAxes.Children(4).Color = [0 0 0]; %Mag=black, dotted
            app.AccelAxes.Children(4).LineStyle =':'; 
            
            pause(0.1);
            app.NodeDropDownValueChanged();
            
            app.updateTimer = timer();
            app.updateTimer.Period = 0.25;
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.BusyMode = 'drop';
            app.updateTimer.TimerFcn = @app.updateDisplays;

            
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            app.node = str2double(app.NodeDropDown.Value);
            
            
            app.PGPCBSerialEditField.Enable = 'on'; 
            app.PGAppRevisionEditField.Enable = 'on'; 
            
            %Check module type
            resp = app.nnp.getType(app.node);
            if ~isempty(resp) && ~isequal(resp, '4ch Pulse Generator PG-4')
                msgbox('Connected device is not a PG', 'PG Test', 'warn');
            end
            
            %Get RM App Revs
            resp = app.nnp.getSwRev(app.node); 
            if length(resp)==1
                app.PGAppRevisionEditField.Value = resp;
                app.PGAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PGAppRevisionEditField.Value = Inf;
                app.PGAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get RM Serial number
            resp = app.nnp.getSerial(app.node);  
            if length(resp)==1
                app.PGPCBSerialEditField.Value = resp;
                app.PGPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PGPCBSerialEditField.Value = Inf; 
                app.PGPCBSerialEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Set diagnostic Mode
            if app.nnp.write(app.node, '3000', 1, 1, 'uint8')~=0 
                msgbox('enter diagnostic mode may have failed')
            end
        end

        % Close request function: PG4DProductionTestUIFigure
        function PG4DProductionTestUIFigureCloseRequest(app, event)
            if ~isempty(app.updateTimer)
                if isvalid(app.updateTimer)
                    stop(app.updateTimer);
                end
                delete(app.updateTimer);
            end
            delete(app);
        end

        % Value changed function: StimButton
        function StimButtonValueChanged(app, event)
            if app.StimButton.Value
                %config max stim on all channels
                for i=1:4
                    if app.nnp.write(app.node, '3212', i, [255 200], 'uint8') ~= 0 
                       msgbox('ch may have been configured')
                    end
                end
                if ~app.nnp.enterTestStim()
                   msgbox('action may have failed')
                end
            else
                if ~app.nnp.enterWaiting()
                    msgbox('action may have failed')
                end
            end
            app.doUpdate = true;
        end

        % Value changed function: VOSCheckButton
        function VOSCheckButtonValueChanged(app, event)
            if app.VOSCheckButton.Value
                if app.nnp.write(app.node, '3210', 3, 0, 'uint16')~=0 %min compliance
                    msgbox('action may have failed')
                end
            else
                if app.nnp.write(app.node, '3210', 3, 2040, 'uint16')~=0 %max compliance
                     msgbox('action may have failed')
                end
            end
            app.doUpdate = true;
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.StartButton.Value == 1     
                if ~isempty(app.updateTimer)
                    app.StartButton.Text = 'Running. Press to Stop';
                    drawnow;

                    app.doUpdate = true;
                    start(app.updateTimer)
                    app.StimButton.Enable = 'on';
                    app.VOSCheckButton.Enable = 'on';
                    app.VNETVINCheckButton.Enable = 'on';
                    return;
                else
                    msgbox('no timer to start')
                    
                end
            else
               if ~isempty(app.updateTimer)
                    stop(app.updateTimer)
               else
                    msgbox('no timer to stop')
               end
            end
            pause(0.5);
            app.StartButton.Text = 'Start';
            app.StimButton.Enable = 'off';
            app.VOSCheckButton.Enable = 'off';
            app.VNETVINCheckButton.Enable = 'off';
            drawnow;

            %app.StartButton.Value = 0;
            
            
        end

        % Value changed function: VNETVINCheckButton
        function VNETVINCheckButtonValueChanged(app, event)
            if app.VNETVINCheckButton.Value
                if ~app.nnp.setVNET(8.5)
                    msgbox('action may have failed')
                end
            else
                if ~app.nnp.setVNET(7.5)
                    msgbox('action may have failed')
                end
            end
            app.doUpdate = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PG4DProductionTestUIFigure = uifigure('Visible', 'off');
            app.PG4DProductionTestUIFigure.Name = 'PG4D Production Test';
            app.PG4DProductionTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @PG4DProductionTestUIFigureCloseRequest, true);
            app.AccelAxes = uiaxes(app.PG4DProductionTestUIFigure);
            title(app.AccelAxes, 'Accelerometer')
            xlabel(app.AccelAxes, 'sample')
            ylabel(app.AccelAxes, 'accelration (g''s)')
            app.TemperatureCEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.TemperatureEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.TemperatureEditField.Value = Inf;
            app.PMPCBSerialEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.PMPCBSerialEditFieldLabel.Text = 'PM PCB Serial #';
            app.PMPCBSerialEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.PMAppRevisionLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.PMAppRevisionLabel.Text = 'PM App Revision';
            app.PMAppRevisionEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.VOSCheckButton = uibutton(app.PG4DProductionTestUIFigure, 'state');
            app.VOSCheckButton.ValueChangedFcn = createCallbackFcn(app, @VOSCheckButtonValueChanged, true);
            app.VOSCheckButton.Text = 'VOS Check';
            app.V3ADUEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.V3ADUEditFieldLabel.Text = '3V3 (ADU)';
            app.V3EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.V3EditField.Editable = 'off';
            app.V3EditField.Value = Inf;
            app.VINADUEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.VINADUEditFieldLabel.Text = 'VIN (ADU)';
            app.VINEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.VINEditField.Editable = 'off';
            app.VINEditField.Value = Inf;
            app.VICADUEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.VICADUEditFieldLabel.Text = 'VIC (ADU)';
            app.VICEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.VICEditField.Editable = 'off';
            app.VICEditField.Value = Inf;
            app.StimButton = uibutton(app.PG4DProductionTestUIFigure, 'state');
            app.StimButton.ValueChangedFcn = createCallbackFcn(app, @StimButtonValueChanged, true);
            app.StimButton.Text = 'Stim';
            app.PWusEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.PWusEditFieldLabel.Text = 'PW (us)';
            app.PW1EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PW1EditField.Editable = 'off';
            app.PW1EditField.Value = Inf;
            app.PAmAEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.PAmAEditFieldLabel.Text = 'PA (mA)';
            app.PA1EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PA1EditField.Editable = 'off';
            app.PA1EditField.Value = Inf;
            app.TmsEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.TmsEditFieldLabel.Text = 'T (ms)';
            app.TEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.TEditField.Editable = 'off';
            app.TEditField.Value = Inf;
            app.VOSVEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.VOSVEditFieldLabel.Text = 'VOS (V)';
            app.VOSEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.VOSEditField.Editable = 'off';
            app.VOSEditField.Value = Inf;
            app.NodeDropDownLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.PG4DProductionTestUIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '9', '10'};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Value = '9';
            app.PGPCBSerialEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.PGPCBSerialEditFieldLabel.Text = 'PG PCB Serial #';
            app.PGPCBSerialEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PGPCBSerialEditField.Editable = 'off';
            app.PGPCBSerialEditField.Value = Inf;
            app.PGAppRevisionLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.PGAppRevisionLabel.Text = 'PG App Revision';
            app.PGAppRevisionEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PGAppRevisionEditField.Editable = 'off';
            app.PGAppRevisionEditField.Value = Inf;
            app.PMVNETVEditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.PMVNETVEditFieldLabel.Text = 'PM VNET (V)';
            app.PMVNETEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PMVNETEditField.Editable = 'off';
            app.PMVNETEditField.Value = Inf;
            app.PW2EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PW2EditField.Value = Inf;
            app.PA2EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PA2EditField.Value = Inf;
            app.PA3EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PA3EditField.Value = Inf;
            app.PW3EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PW3EditField.Value = Inf;
            app.PA4EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PA4EditField.Value = Inf;
            app.PW4EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.PW4EditField.Value = Inf;
            app.complianceLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.complianceLabel.Text = 'compliance';
            app.C1EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.C1EditField.Editable = 'off';
            app.C1EditField.Value = Inf;
            app.C2EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.C2EditField.Editable = 'off';
            app.C2EditField.Value = Inf;
            app.C3EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.C3EditField.Editable = 'off';
            app.C3EditField.Value = Inf;
            app.C4EditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.C4EditField.Editable = 'off';
            app.C4EditField.Value = Inf;
            app.StartButton = uibutton(app.PG4DProductionTestUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.XLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.XLabel.Text = 'X';
            app.XEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.XEditField.Editable = 'off';
            app.XEditField.Value = Inf;
            app.YLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.YLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Value = Inf;
            app.ZLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.ZLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Value = Inf;
            app.EditFieldLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.EditFieldLabel.Text = '#';
            app.CntEditField = uieditfield(app.PG4DProductionTestUIFigure, 'numeric');
            app.CntEditField.Editable = 'off';
            app.CntEditField.Value = Inf;
            app.VNETVINCheckButton = uibutton(app.PG4DProductionTestUIFigure, 'state');
            app.VNETVINCheckButton.ValueChangedFcn = createCallbackFcn(app, @VNETVINCheckButtonValueChanged, true);
            app.VNETVINCheckButton.Text = 'VNET/VIN Check';
            app.Label = uilabel(app.PG4DProductionTestUIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.PG4DProductionTestUIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.PG4DProductionTestUIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PG4DProductionTestUIFigure);
            app.Label_4.Text = '4';
            app.ChannelLabel = uilabel(app.PG4DProductionTestUIFigure);
            app.ChannelLabel.Text = 'Channel';
            % Show the figure after all components are created
            app.PG4DProductionTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pgtest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PG4DProductionTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PG4DProductionTestUIFigure)
        end
    end
end