classdef pmtest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PMTestUIFigure                matlab.ui.Figure
        StartButton                   matlab.ui.control.StateButton
        LoadEditField                 matlab.ui.control.NumericEditField
        LoadmALabel                   matlab.ui.control.Label
        VSYSEditField                 matlab.ui.control.NumericEditField
        VSYSVLabel                    matlab.ui.control.Label
        VNETEditField                 matlab.ui.control.NumericEditField
        VNETVLabel                    matlab.ui.control.Label
        VRECEditField                 matlab.ui.control.NumericEditField
        VRECVEditFieldLabel           matlab.ui.control.Label
        ExternalFlashandRAMPanel      matlab.ui.container.Panel
        EraseBlockButton              matlab.ui.control.Button
        AddressEditField              matlab.ui.control.EditField
        Address0xLabel                matlab.ui.control.Label
        ValueEditField                matlab.ui.control.EditField
        Value0xEditFieldLabel         matlab.ui.control.Label
        MemoryDropDown                matlab.ui.control.DropDown
        MemoryEditField               matlab.ui.control.EditField
        WriteButton                   matlab.ui.control.Button
        ReadButton                    matlab.ui.control.Button
        PMVNETEditField               matlab.ui.control.NumericEditField
        VNETLabel                     matlab.ui.control.Label
        NetworkButton                 matlab.ui.control.StateButton
        Battery3Panel                 matlab.ui.container.Panel
        CycleCount3EditField          matlab.ui.control.NumericEditField
        CycleCountEditField_3Label    matlab.ui.control.Label
        Temperature3EditField         matlab.ui.control.NumericEditField
        TemperatureCEditField_3Label  matlab.ui.control.Label
        Status3EditField              matlab.ui.control.NumericEditField
        StatusEditField_3Label        matlab.ui.control.Label
        Current3EditField             matlab.ui.control.NumericEditField
        CurrentmAEditField_3Label     matlab.ui.control.Label
        Voltage3EditField             matlab.ui.control.NumericEditField
        VoltagemVLabel_3              matlab.ui.control.Label
        Battery2Panel                 matlab.ui.container.Panel
        CycleCount2EditField          matlab.ui.control.NumericEditField
        CycleCountEditField_2Label    matlab.ui.control.Label
        Temperature2EditField         matlab.ui.control.NumericEditField
        TemperatureCEditField_2Label  matlab.ui.control.Label
        Status2EditField              matlab.ui.control.NumericEditField
        StatusEditField_2Label        matlab.ui.control.Label
        Current2EditField             matlab.ui.control.NumericEditField
        CurrentmAEditField_2Label     matlab.ui.control.Label
        Voltage2EditField             matlab.ui.control.NumericEditField
        VoltagemVLabel_2              matlab.ui.control.Label
        Battery1Panel                 matlab.ui.container.Panel
        CycleCount1EditField          matlab.ui.control.NumericEditField
        CycleCountEditFieldLabel      matlab.ui.control.Label
        Temperature1EditField         matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel_2  matlab.ui.control.Label
        Status1EditField              matlab.ui.control.NumericEditField
        StatusEditFieldLabel          matlab.ui.control.Label
        Current1EditField             matlab.ui.control.NumericEditField
        CurrentmAEditFieldLabel       matlab.ui.control.Label
        Voltage1EditField             matlab.ui.control.NumericEditField
        VoltagemVLabel                matlab.ui.control.Label
        PMAppRevisionEditField        matlab.ui.control.NumericEditField
        PMAppRevisionEditFieldLabel   matlab.ui.control.Label
        CntEditField                  matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        PMPCBSerialEditField          matlab.ui.control.NumericEditField
        PMPCBSerialEditFieldLabel     matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XLabel                        matlab.ui.control.Label
        TemperatureEditField          matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel    matlab.ui.control.Label
        AccelAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        nnp = []; 
        nSamp = [];
        plotbuf = [];
        updateTimer = [];
        doUpdate = false;
        pauseTime = 0.01;
    end

    properties (Access = private)
        updateCount = 1;
    end

    methods (Access = public)
    
        function updateDisplays(app, obj, event)

            %Get Accel
            accel=[];
            resp = app.nnp.read(7, '2011', 1, 'uint8');
            if length(resp)==4
                if all(resp==255)
                    accel = [Inf Inf Inf];
                    mag = Inf;
                else
                    accel = [0 0 0];
                    for i=1:3
                        accel(i) = (double((bitshift(resp(i), -2))) - 32)/16; %convert to gs for plot
                    end
                    mag = norm(accel);
                end
                app.XEditField.Value = double(resp(1));
                app.YEditField.Value = double(resp(2));
                app.ZEditField.Value = double(resp(3));
                app.CntEditField.Value = double(resp(4));
            end

            if ~isempty(accel) 
                app.plotbuf(1:end-1,:) = app.plotbuf(2:end,:);
                app.plotbuf(end,:) = [accel'; mag];
                for i = 1:4
                    app.AccelAxes.Children(i).YData = app.plotbuf(:,i); 
                    
                end              
            end

                    
            drawnow;
            %pause(app.pauseTime);
            
            switch app.updateCount
                
                case 1
                    resp = double(app.nnp.read(7, '3001', 1, 'uint8'));
                    if length(resp) == 16
                        app.Temperature1EditField.Value = (resp(2)*256 + resp(1))*0.25-273;
                        app.CycleCount1EditField.Value = resp(4)*256 + resp(3);
                        app.Voltage1EditField.Value = resp(8)*256 + resp(7);
                        status = resp(13);
                        current = (resp(6)*256 + resp(5))*3.57/20;
                        if status <128 %high bit indicates charging
                            current = -current;
                        end
                        app.Current1EditField.Value = current; 
                        app.Status1EditField.Value = status;
                    else
                        app.Temperature1EditField.Value = Inf;
                        app.CycleCount1EditField.Value = Inf;
                        app.Current1EditField.Value = Inf;
                        app.Voltage1EditField.Value = Inf;
                        app.Status1EditField.Value = Inf;
                    end
                
                case 2
                    resp = double(app.nnp.read(7, '3002', 1, 'uint8'));
                    if length(resp) == 16
                        app.Temperature2EditField.Value = (resp(2)*256 + resp(1))*0.25-273;
                        app.CycleCount2EditField.Value = resp(4)*256 + resp(3);
                        app.Voltage2EditField.Value = resp(8)*256 + resp(7);
                        status = resp(13);
                        current = (resp(6)*256 + resp(5))*3.57/20;
                        if status <128 %high bit indicates charging
                            current = -current;
                        end
                        app.Current2EditField.Value = current; 
                        app.Status2EditField.Value = status;
                    else
                        app.Temperature2EditField.Value = Inf;
                        app.CycleCount2EditField.Value = Inf;
                        app.Current2EditField.Value = Inf;
                        app.Voltage2EditField.Value = Inf;
                        app.Status2EditField.Value = Inf;
                    end
                case 3
                    resp = double(app.nnp.read(7, '3003', 1, 'uint8'));
                    if length(resp) == 16
                        app.Temperature3EditField.Value = (resp(2)*256 + resp(1))*0.25-273;
                        app.CycleCount3EditField.Value = resp(4)*256 + resp(3);
                        app.Voltage3EditField.Value = resp(8)*256 + resp(7);
                        status = resp(13);
                        current = (resp(6)*256 + resp(5))*3.57/20;
                        if status <128 %high bit indicates charging
                            current = -current;
                        end
                        app.Current3EditField.Value = current; 
                        app.Status3EditField.Value = status;
                    else
                        app.Temperature3EditField.Value = Inf;
                        app.CycleCount3EditField.Value = Inf;
                        app.Current3EditField.Value = Inf;
                        app.Voltage3EditField.Value = Inf;
                        app.Status3EditField.Value = Inf;
                    end
                case 4
                    resp = double(app.nnp.read(7, '3000', 7, 4, 'uint16'));
                    if length(resp) == 4
                        app.VRECEditField.Value = resp(1)/10;
                        app.VSYSEditField.Value = resp(2)/10;
                        app.VNETEditField.Value = resp(3)/10;
                        app.LoadEditField.Value = resp(4);
                    else
                        app.VRECEditField.Value = Inf;
                        app.VSYSEditField.Value = Inf;
                        app.VNETEditField.Value = Inf;
                        app.LoadEditField.Value = Inf;
                    end
                case 5
                    resp = app.nnp.getTemp(7);
                    if length(resp) == 1
                        app.TemperatureEditField.Value = resp;
                    else
                        app.TemperatureEditField.Value = Inf;
                    end
                otherwise
            end


            app.updateCount = app.updateCount+1;
            if app.updateCount == 6
                app.updateCount = 1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
           if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            app.EraseBlockButton.Visible = 'off';
            
            app.PMPCBSerialEditField.Enable = 'on';  
            app.PMAppRevisionEditField.Enable = 'on';  
            
            %Get PM App Revs
            resp = app.nnp.getSwRev(7); 
            if length(resp)==1
                app.PMAppRevisionEditField.Value = resp(1);
                app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PMAppRevisionEditField.Value = Inf;
                app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
             %Get PM Serial number
            resp = app.nnp.getSerial(7);  
            if length(resp)==1
                app.PMPCBSerialEditField.Value = resp(1);
                app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PMPCBSerialEditField.Value = Inf; 
                app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
            end
                        
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM');
                return;
            end 
            
             resp = app.nnp.getNetworkStatus();
            if isempty(resp)
                msgbox('Could not confirm Network Status. Toggle Network button to update');
                app.NetworkButton.Text = {'Network'; 'Status'; 'is Unknown'};
            elseif resp == 0
                app.NetworkButton.Text = {'Network'; 'is'; 'OFF'};
                app.NetworkButton.Value = 0;
            else 
                app.NetworkButton.Text = {'Network'; 'is'; 'ON'};
                app.NetworkButton.Value = 1;
            end
            
            %initialize Memory values
            app.MemoryDropDownValueChanged(0);
            
            app.nSamp = 100;
            app.plotbuf = zeros(app.nSamp,4);
            plot(app.AccelAxes, 1:app.nSamp, app.plotbuf);
            app.AccelAxes.XLim = [0 app.nSamp];
            app.AccelAxes.YLim = [-2 2];
            app.AccelAxes.Children(1).Color = [1 0 0]; %X=red
            app.AccelAxes.Children(2).Color = [0 .5 0];%Y=green
            app.AccelAxes.Children(3).Color = [0 0 1]; %Z=blue
            app.AccelAxes.Children(4).Color = [0 0 0]; %Mag=black, dotted
            app.AccelAxes.Children(4).LineStyle =':'; 
            
            app.updateTimer = timer();
            app.updateTimer.Period = 0.25;
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.BusyMode = 'drop';
            app.updateTimer.TimerFcn = @app.updateDisplays;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.StartButton.Value == 1     
                if ~isempty(app.updateTimer)
                    app.StartButton.Text = 'Running. Press to Stop';
                    app.WriteButton.Enable = 'off';
                    app.ReadButton.Enable = 'off';
                    drawnow;

                    app.doUpdate = true;
                    start(app.updateTimer)
                    return;
                else
                    msgbox('no timer to start')
                    
                end
            else
               if ~isempty(app.updateTimer)
                    stop(app.updateTimer)
               else
                    msgbox('no timer to stop')
               end
            end
            pause(0.5);
            app.StartButton.Text = 'Start';
            app.WriteButton.Enable = 'on';
            app.ReadButton.Enable = 'on';
            drawnow;
        end

        % Value changed function: NetworkButton
        function NetworkButtonValueChanged(app, event)
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM.  Network Setting may not work');
            end
            if app.NetworkButton.Value 
                if ~app.nnp.networkOn()
                    msgbox('could not turn on Network.');
                end
                pause(0.2); %pause after tunring on Network before confirming
            else
                if ~ app.nnp.networkOff()
                    msgbox('could not turn off Network.');
                end
            end
            
            
            resp = app.nnp.getNetworkStatus();
            if isempty(resp)
                msgbox('Could not confirm Network Status. Toggle Network button to update');
                app.NetworkButton.Text = {'Network'; 'Status'; 'is Unknown'};
            elseif resp == 0
                app.NetworkButton.Text = {'Network'; 'is'; 'OFF'};
                app.NetworkButton.Value = 0;
            else 
                app.NetworkButton.Text = {'Network'; 'is'; 'ON'};
                app.NetworkButton.Value = 1;
            end
            
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)

            app.MemoryEditField.Value = '';
            drawnow; pause(0.1);
            mode = str2double(app.MemoryDropDown.Value);
            
            maxFlash = hex2dec('400000')-32;
            maxRAM = hex2dec('8000')-32;
            address = sscanf(app.AddressEditField.Value, '%X');
            if mode == 2 %Flash
                if length(address) ~=1
                    msgbox('address must be less entered as one hex value (e.g. 2456A0');
                    return
                elseif address > maxFlash
                    msgbox(sprintf('address must be less than 0x%06X', maxFlash));
                    return
                end
            elseif mode==3 %RAM
                if length(address) ~=1
                    msgbox('address must be less entered as one hex value (e.g. 12F4');
                    return
                elseif address > maxRAM
                    msgbox(sprintf('address must be less than 0x%04X', maxRAM));
                    return
                end
            end

            addr = uint32(address);
            addrBytes = [rem(addr, 256),  rem(bitshift(addr, -8), 256), rem(bitshift(addr, -16), 256), rem(bitshift(addr, -24), 256)];
            resp = app.nnp.write(7, '2020', 1, addrBytes);
            if ~isequal(resp, 0)
                msgbox('could not write address', 'PM Read Memory', 'error')
                return
            end
            
            resp = app.nnp.write(7, '2020', 5, 1);
            if ~isequal(resp, 0)
                msgbox('could not trigger Read', 'PM Read Memory', 'error')
                return
            end
            
            resp = app.nnp.write(7, '2020', 4, mode);
            if ~isequal(resp, 0)
                msgbox('could not set Memory mode', 'PM Read Memory', 'error')
                return
            end
            
            pause(.5)
            mem = Inf;
            
            resp = app.nnp.read(7, '2020', 2);
            if isempty(resp)
                msgbox('could not read Memory', 'PM Read Memory', 'error')
                return
            elseif length(resp)==36
                mem = resp(1:32);
            end
            
            resp = app.nnp.read(7, '2020', 7);
            if ~isequal(resp, 0)
                msgbox('could not read Memory Read status', 'PM Read Memory', 'error')
                return
            end
            
            app.MemoryEditField.Value = sprintf('%02X ',mem);
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            mode = str2double(app.MemoryDropDown.Value);
            
            maxFlash = hex2dec('400000') -1;
            minFlash = hex2dec('1000');
            maxRAM = hex2dec('8000')-1;
            address = sscanf(app.AddressEditField.Value, '%X');
            if mode == 2 %Flash
                if length(address) ~=1
                    msgbox('address must be less entered as one hex value (e.g. 2456A0');
                    return
                elseif address > maxFlash
                    msgbox(sprintf('address must be less than 0x%06X', maxFlash));
                    return
                elseif address < minFlash
                    msgbox('you are attempting to write within the OD restore space')
                end
            elseif mode==3 %RAM
                if length(address) ~=1
                    msgbox('address must be less entered as one hex value (e.g. 12F4');
                    return
                elseif address > maxRAM
                    msgbox(sprintf('address must be less than 0x%04X', maxRAM));
                    return
                end
            end

            value = sscanf(app.ValueEditField.Value, '%X');
            if length(value) ~= 1
                msgbox('value must be single byte entered in hex');
                return
            elseif value > 256
                msgbox('value must be <= 0xFF');
                return
            end
            
            addr = uint32(address);
            addrBytes = [rem(addr, 256),  rem(bitshift(addr, -8), 256), rem(bitshift(addr, -16), 256), rem(bitshift(addr, -24), 256)]
            resp = app.nnp.write(7, '2020', 1, addrBytes);
            if ~isequal(resp, 0)
                msgbox('could not write address', 'PM Write Memory', 'error')
                return
            end
            
            resp = app.nnp.write(7, '2020', 5, 1);
            if ~isequal(resp, 0)
                msgbox('could not trigger Write', 'PM Write Memory', 'error')
                return
            end
            
            resp = app.nnp.write(7, '2020', 6, value);
            if ~isequal(resp, 0)
                msgbox('could not set Memory mode', 'PM Write Memory', 'error')
                return
            end
            
            resp = app.nnp.write(7, '2020', 4, mode+4);
            if ~isequal(resp, 0)
                msgbox('could not set Memory mode', 'PM Write Memory', 'error')
                return
            end
            
            pause(0.5);
            
            resp = app.nnp.write(7, '2020', 4, 0);
            if ~isequal(resp, 0)
                msgbox('could not set Memory mode', 'PM Write Memory', 'error')
                return
            end
            
            pause(.5)
            
            resp = app.nnp.read(7, '2020', 7);
            if ~isequal(resp, 0)
                msgbox('could not read Memory Write status', 'PM Write Memory', 'error')
                return
            end
            
            resp = questdlg('Write completed successfully. Do you want to read back to verify?');
            if isequal(resp, 'Yes')
                app.ReadButtonPushed(event);
            end
        end

        % Value changed function: MemoryDropDown
        function MemoryDropDownValueChanged(app, event)
            
            if str2double(app.MemoryDropDown.Value) == 2 %Flash
                app.EraseBlockButton.Visible = 'on';
                app.AddressEditField.Value = '001000'; %default starting address
                app.ValueEditField.Value = 'CD'; %default write value
            else
                app.EraseBlockButton.Visible = 'off';
                app.AddressEditField.Value = '0000'; %default starting address
                app.ValueEditField.Value = 'AB'; %default write value
            end
        end

        % Button pushed function: EraseBlockButton
        function EraseBlockButtonPushed(app, event)
            BlockSize = hex2dec('1000');
            maxFlash = hex2dec('400000') -1;
            minFlash = hex2dec('1000');
            address = sscanf(app.AddressEditField.Value, '%X');
            if length(address) ~=1
                msgbox('address must be less entered as one hex value (e.g. 2456A0');
                return
            elseif address > maxFlash
                msgbox(sprintf('address must be less than 0x%06X', maxFlash));
                return
            elseif address < minFlash
                msgbox('Cannot erase the OD restore space (Block 0)')
            end
            block = uint16(floor(address/BlockSize));
            blockBytes = [rem(block,256), floor(block/256)];
            app.nnp.nmt(7, 'bb', blockBytes(1), blockBytes(2));
            
            resp = questdlg('Erase block completed successfully. Do you want to read back to verify?');
            if isequal(resp, 'Yes')
                app.ReadButtonPushed(event);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PMTestUIFigure = uifigure('Visible', 'off');
            app.PMTestUIFigure.Name = 'PM Test';
            app.AccelAxes = uiaxes(app.PMTestUIFigure);
            title(app.AccelAxes, 'Accelerometer')
            xlabel(app.AccelAxes, 'sample')
            ylabel(app.AccelAxes, 'accelration (g''s)')
            app.TemperatureCEditFieldLabel = uilabel(app.PMTestUIFigure);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.TemperatureEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%.1f';
            app.TemperatureEditField.Editable = 'off';
            app.TemperatureEditField.Value = Inf;
            app.XLabel = uilabel(app.PMTestUIFigure);
            app.XLabel.Text = 'X';
            app.XEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.XEditField.Editable = 'off';
            app.XEditField.Value = Inf;
            app.PMPCBSerialEditFieldLabel = uilabel(app.PMTestUIFigure);
            app.PMPCBSerialEditFieldLabel.Text = 'PM PCB Serial #';
            app.PMPCBSerialEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.YEditFieldLabel = uilabel(app.PMTestUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Value = Inf;
            app.ZEditFieldLabel = uilabel(app.PMTestUIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Value = Inf;
            app.EditFieldLabel = uilabel(app.PMTestUIFigure);
            app.EditFieldLabel.Text = '#';
            app.CntEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.CntEditField.Editable = 'off';
            app.CntEditField.Value = Inf;
            app.PMAppRevisionEditFieldLabel = uilabel(app.PMTestUIFigure);
            app.PMAppRevisionEditFieldLabel.Text = 'PM App Revision';
            app.PMAppRevisionEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.Battery1Panel = uipanel(app.PMTestUIFigure);
            app.Battery1Panel.Title = 'Battery 1';
            app.VoltagemVLabel = uilabel(app.Battery1Panel);
            app.VoltagemVLabel.Text = 'Voltage (mV)';
            app.Voltage1EditField = uieditfield(app.Battery1Panel, 'numeric');
            app.Voltage1EditField.Editable = 'off';
            app.Voltage1EditField.Value = Inf;
            app.CurrentmAEditFieldLabel = uilabel(app.Battery1Panel);
            app.CurrentmAEditFieldLabel.Text = 'Current (mA)';
            app.Current1EditField = uieditfield(app.Battery1Panel, 'numeric');
            app.Current1EditField.ValueDisplayFormat = '%+6.2f';
            app.Current1EditField.Editable = 'off';
            app.Current1EditField.Value = Inf;
            app.StatusEditFieldLabel = uilabel(app.Battery1Panel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.Status1EditField = uieditfield(app.Battery1Panel, 'numeric');
            app.Status1EditField.ValueDisplayFormat = '0x%02X';
            app.Status1EditField.Editable = 'off';
            app.Status1EditField.Value = Inf;
            app.TemperatureCEditFieldLabel_2 = uilabel(app.Battery1Panel);
            app.TemperatureCEditFieldLabel_2.Text = 'Temperature (°C)';
            app.Temperature1EditField = uieditfield(app.Battery1Panel, 'numeric');
            app.Temperature1EditField.ValueDisplayFormat = '%.1f';
            app.Temperature1EditField.Editable = 'off';
            app.Temperature1EditField.Value = Inf;
            app.CycleCountEditFieldLabel = uilabel(app.Battery1Panel);
            app.CycleCountEditFieldLabel.Text = 'Cycle Count';
            app.CycleCount1EditField = uieditfield(app.Battery1Panel, 'numeric');
            app.CycleCount1EditField.Editable = 'off';
            app.CycleCount1EditField.Value = Inf;
            app.Battery2Panel = uipanel(app.PMTestUIFigure);
            app.Battery2Panel.Title = 'Battery 2';
            app.VoltagemVLabel_2 = uilabel(app.Battery2Panel);
            app.VoltagemVLabel_2.Text = 'Voltage (mV)';
            app.Voltage2EditField = uieditfield(app.Battery2Panel, 'numeric');
            app.Voltage2EditField.Editable = 'off';
            app.Voltage2EditField.Value = Inf;
            app.CurrentmAEditField_2Label = uilabel(app.Battery2Panel);
            app.CurrentmAEditField_2Label.Text = 'Current (mA)';
            app.Current2EditField = uieditfield(app.Battery2Panel, 'numeric');
            app.Current2EditField.ValueDisplayFormat = '%+6.2f';
            app.Current2EditField.Editable = 'off';
            app.Current2EditField.Value = Inf;
            app.StatusEditField_2Label = uilabel(app.Battery2Panel);
            app.StatusEditField_2Label.Text = 'Status';
            app.Status2EditField = uieditfield(app.Battery2Panel, 'numeric');
            app.Status2EditField.ValueDisplayFormat = '0x%02X';
            app.Status2EditField.Editable = 'off';
            app.Status2EditField.Value = Inf;
            app.TemperatureCEditField_2Label = uilabel(app.Battery2Panel);
            app.TemperatureCEditField_2Label.Text = 'Temperature (°C)';
            app.Temperature2EditField = uieditfield(app.Battery2Panel, 'numeric');
            app.Temperature2EditField.ValueDisplayFormat = '%.1f';
            app.Temperature2EditField.Editable = 'off';
            app.Temperature2EditField.Value = Inf;
            app.CycleCountEditField_2Label = uilabel(app.Battery2Panel);
            app.CycleCountEditField_2Label.Text = 'Cycle Count';
            app.CycleCount2EditField = uieditfield(app.Battery2Panel, 'numeric');
            app.CycleCount2EditField.Editable = 'off';
            app.CycleCount2EditField.Value = Inf;
            app.Battery3Panel = uipanel(app.PMTestUIFigure);
            app.Battery3Panel.Title = 'Battery 3';
            app.VoltagemVLabel_3 = uilabel(app.Battery3Panel);
            app.VoltagemVLabel_3.Text = 'Voltage (mV)';
            app.Voltage3EditField = uieditfield(app.Battery3Panel, 'numeric');
            app.Voltage3EditField.Editable = 'off';
            app.Voltage3EditField.Value = Inf;
            app.CurrentmAEditField_3Label = uilabel(app.Battery3Panel);
            app.CurrentmAEditField_3Label.Text = 'Current (mA)';
            app.Current3EditField = uieditfield(app.Battery3Panel, 'numeric');
            app.Current3EditField.ValueDisplayFormat = '%+6.2f';
            app.Current3EditField.Editable = 'off';
            app.Current3EditField.Value = Inf;
            app.StatusEditField_3Label = uilabel(app.Battery3Panel);
            app.StatusEditField_3Label.Text = 'Status';
            app.Status3EditField = uieditfield(app.Battery3Panel, 'numeric');
            app.Status3EditField.ValueDisplayFormat = '0x%02X';
            app.Status3EditField.Editable = 'off';
            app.Status3EditField.Value = Inf;
            app.TemperatureCEditField_3Label = uilabel(app.Battery3Panel);
            app.TemperatureCEditField_3Label.Text = 'Temperature (°C)';
            app.Temperature3EditField = uieditfield(app.Battery3Panel, 'numeric');
            app.Temperature3EditField.ValueDisplayFormat = '%.1f';
            app.Temperature3EditField.Editable = 'off';
            app.Temperature3EditField.Value = Inf;
            app.CycleCountEditField_3Label = uilabel(app.Battery3Panel);
            app.CycleCountEditField_3Label.Text = 'Cycle Count';
            app.CycleCount3EditField = uieditfield(app.Battery3Panel, 'numeric');
            app.CycleCount3EditField.Editable = 'off';
            app.CycleCount3EditField.Value = Inf;
            app.NetworkButton = uibutton(app.PMTestUIFigure, 'state');
            app.NetworkButton.ValueChangedFcn = createCallbackFcn(app, @NetworkButtonValueChanged, true);
            app.NetworkButton.Text = 'Network ';
            app.VNETLabel = uilabel(app.PMTestUIFigure);
            app.VNETLabel.Text = 'VNET';
            app.PMVNETEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.PMVNETEditField.Limits = [4.6 9.5];
            app.PMVNETEditField.ValueDisplayFormat = '%2.1f';
            app.PMVNETEditField.Value = 7.5;
            app.ExternalFlashandRAMPanel = uipanel(app.PMTestUIFigure);
            app.ExternalFlashandRAMPanel.Title = 'External Flash and RAM';
            app.ReadButton = uibutton(app.ExternalFlashandRAMPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.WriteButton = uibutton(app.ExternalFlashandRAMPanel, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.MemoryEditField = uieditfield(app.ExternalFlashandRAMPanel, 'text');
            app.MemoryEditField.Editable = 'off';
            app.MemoryDropDown = uidropdown(app.ExternalFlashandRAMPanel);
            app.MemoryDropDown.Items = {'RAM', 'Flash'};
            app.MemoryDropDown.ItemsData = {'3', '2'};
            app.MemoryDropDown.ValueChangedFcn = createCallbackFcn(app, @MemoryDropDownValueChanged, true);
            app.MemoryDropDown.Value = '3';
            app.Value0xEditFieldLabel = uilabel(app.ExternalFlashandRAMPanel);
            app.Value0xEditFieldLabel.Text = 'Value: 0x';
            app.ValueEditField = uieditfield(app.ExternalFlashandRAMPanel, 'text');
            app.ValueEditField.Value = 'AA';
            app.Address0xLabel = uilabel(app.ExternalFlashandRAMPanel);
            app.Address0xLabel.Text = 'Address: 0x';
            app.AddressEditField = uieditfield(app.ExternalFlashandRAMPanel, 'text');
            app.AddressEditField.Value = '000000';
            app.EraseBlockButton = uibutton(app.ExternalFlashandRAMPanel, 'push');
            app.EraseBlockButton.ButtonPushedFcn = createCallbackFcn(app, @EraseBlockButtonPushed, true);
            app.EraseBlockButton.Text = 'Erase Block';
            app.VRECVEditFieldLabel = uilabel(app.PMTestUIFigure);
            app.VRECVEditFieldLabel.Text = 'VREC (V)';
            app.VRECEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.VRECEditField.ValueDisplayFormat = '%.1f';
            app.VRECEditField.Editable = 'off';
            app.VRECEditField.Value = Inf;
            app.VNETVLabel = uilabel(app.PMTestUIFigure);
            app.VNETVLabel.Text = 'VNET (V)';
            app.VNETEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.VNETEditField.ValueDisplayFormat = '%.1f';
            app.VNETEditField.Editable = 'off';
            app.VNETEditField.Value = Inf;
            app.VSYSVLabel = uilabel(app.PMTestUIFigure);
            app.VSYSVLabel.Text = 'VSYS (V)';
            app.VSYSEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.VSYSEditField.ValueDisplayFormat = '%.1f';
            app.VSYSEditField.Editable = 'off';
            app.VSYSEditField.Value = Inf;
            app.LoadmALabel = uilabel(app.PMTestUIFigure);
            app.LoadmALabel.Text = 'Load (mA)';
            app.LoadEditField = uieditfield(app.PMTestUIFigure, 'numeric');
            app.LoadEditField.Editable = 'off';
            app.LoadEditField.Value = Inf;
            app.StartButton = uibutton(app.PMTestUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.PMTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pmtest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PMTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PMTestUIFigure)
        end
    end
end