classdef rmbootloader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RMBootloader                 matlab.ui.Figure
        SetStimLimitsButton          matlab.ui.control.Button
        SupportsPM430Label           matlab.ui.control.Label
        BootRevEditField             matlab.ui.control.EditField
        BootRevEditFieldLabel        matlab.ui.control.Label
        RemoteModuleFlashPanel       matlab.ui.container.Panel
        VerifyCheckBox               matlab.ui.control.CheckBox
        WriteCheckBox                matlab.ui.control.CheckBox
        BlankCheckCheckBox           matlab.ui.control.CheckBox
        EraseCheckBox                matlab.ui.control.CheckBox
        StartButton                  matlab.ui.control.Button
        ExitBootloaderStartAppPanel  matlab.ui.container.Panel
        RestoreODDefaultsCheckBox    matlab.ui.control.CheckBox
        StartAllButton               matlab.ui.control.Button
        StartSelectedButton          matlab.ui.control.Button
        ModuleTypeEditField          matlab.ui.control.EditField
        ModuleTypeEditFieldLabel     matlab.ui.control.Label
        ChangeNodeButton             matlab.ui.control.Button
        NodeScanListTextArea         matlab.ui.control.TextArea
        NodeScanListTextAreaLabel    matlab.ui.control.Label
        SelectNodeButton             matlab.ui.control.StateButton
        PowerModuleSettingsPanel     matlab.ui.container.Panel
        PMVNETEditField              matlab.ui.control.NumericEditField
        VNETLabel                    matlab.ui.control.Label
        PMAppRevisionEditField       matlab.ui.control.NumericEditField
        AppRevEditFieldLabel         matlab.ui.control.Label
        PMPCBSerialEditField         matlab.ui.control.NumericEditField
        SerialEditField_2Label       matlab.ui.control.Label
        NetworkButton                matlab.ui.control.StateButton
        SerialEditField              matlab.ui.control.NumericEditField
        SerialEditFieldLabel         matlab.ui.control.Label
        ScanModeButtonGroup          matlab.ui.container.ButtonGroup
        ContinuousButton             matlab.ui.control.RadioButton
        ListedNodesButton            matlab.ui.control.RadioButton
        AllNodesButton               matlab.ui.control.RadioButton
        ListBox                      matlab.ui.control.ListBox
        ScanforNodesButton           matlab.ui.control.Button
        NodeEditField                matlab.ui.control.NumericEditField
        NodeLabel                    matlab.ui.control.Label
    end


    properties (Access = public)
        nnp = []; % Description
        callingFigure=[]; %handle to calling figure
        maxAppSize = 0;
        maxPacketSize = 0;
    end

    methods (Access = private)
    
        function handleErrors(app, err, title)
            switch err
                case 0 %don't do anything
                case 1, msgbox('PM internal or CAN error (node may not be on network)', title, 'error'); 
                case 2, msgbox('PM response is too short', title, 'error'); 
                case 3, msgbox('Radio Timeout', title, 'error'); 
                case 4, msgbox('Unknown Response from Access Point', title, 'error'); 
                case 5, msgbox('No response from Access Point', title, 'error'); 
                case 6, msgbox('PM response does not echo request', title, 'error'); 
                case 7, msgbox('Bad CRC', title, 'error'); 
                otherwise msgbox('Unknown error', title, 'error'); 
            end
        end
        
        function enableAll(app, enable)
            %enables or disables all buttons
            if app.NetworkButton.Value == 1
                app.ScanforNodesButton.Enable = enable;
                app.SelectNodeButton.Enable = enable;
                app.StartAllButton.Enable = enable;
                app.RestoreODDefaultsCheckBox.Enable = enable;
            else
                app.ScanforNodesButton.Enable = 'off';
                app.SelectNodeButton.Enable = 'off';
                app.StartAllButton.Enable = 'off';
                app.RestoreODDefaultsCheckBox.Enable = 'off';
            end
            app.AllNodesButton.Enable = enable;
            app.ListedNodesButton.Enable = enable;
            app.ContinuousButton.Enable = enable;
            app.NetworkButton.Enable = enable;
            app.PMVNETEditField.Enable = enable;


            if app.AllNodesButton.Value == 1
                app.NodeScanListTextArea.Enable = 'off';
            else
                app.NodeScanListTextArea.Enable = enable;
            end
            if app.SelectNodeButton.Value == 1
                app.StartButton.Enable = enable;
                app.ChangeNodeButton.Enable = enable;
                app.EraseCheckBox.Enable = enable;
                app.BlankCheckCheckBox.Enable = enable;
                app.WriteCheckBox.Enable = enable;
                app.VerifyCheckBox.Enable = enable;
                app.StartSelectedButton.Enable = enable;
                app.SerialEditField.Enable = enable;
                app.ModuleTypeEditField.Enable = enable;
                app.BootRevEditField.Enable = enable;
                app.NodeEditField.Enable = 'off';
                app.ListBox.Enable = 'off';
            else
                app.StartButton.Enable = 'off';
                app.ChangeNodeButton.Enable = 'off';
                app.EraseCheckBox.Enable = 'off';
                app.BlankCheckCheckBox.Enable = 'off';
                app.WriteCheckBox.Enable = 'off';
                app.VerifyCheckBox.Enable = 'off';
                app.StartSelectedButton.Enable = 'off';
                app.SerialEditField.Enable = 'off';
                app.ModuleTypeEditField.Enable = 'off';
                app.BootRevEditField.Enable = 'off';
                app.NodeEditField.Enable = 'on';
                app.ListBox.Enable = enable;
            end
        end
        
        function DeselectNode(app)
             app.SelectNodeButton.Value = 0;
             app.SelectNodeButton.Text = 'Select Node';
             app.SerialEditField.Value = 0;
             app.ModuleTypeEditField.Value = '';
             app.BootRevEditField.Value = '';
             app.ListBox.Items = {};
             app.enableAll('on');
             app.maxAppSize = 0;
             app.maxPacketSize = 0;
             app.SetStimLimitsButton.Visible = 'off';
        end
        
        function SelectNode(app)
             app.SelectNodeButton.Value = 1;
             app.SelectNodeButton.Text = 'Node Selected. Press to deselect';
             app.ListBox.Items = {};
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

           
            app.enableAll('off');
            
            %Get AP AppRev
            resp = app.nnp.getAPRev();  
            if length(resp) ~= 1 || resp(1) < 3
                msgbox('Access Point must be at Rev 3 or higher');
                return;
            end
                
            
            %Get PM Serial number
            resp = app.nnp.getSerial(7);  
            if length(resp)==1
                app.PMPCBSerialEditField.Value = resp(1);
                app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PMPCBSerialEditField.Value = Inf; 
                app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
            end
            
            %Get SW Rev
            resp = app.nnp.getSwRev(7);  
            if length(resp)==1
                app.PMAppRevisionEditField.Value = resp(1);
                app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PMAppRevisionEditField.Value = Inf; 
                app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            %pause(app.pauseTime);


            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM');
                return
            end
            
            if ~app.nnp.networkOff()
                msgbox('could not turn off network');
                return
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.setVNET(7.5)
                msgbox('could not set PM VNET');
                return
            end
            
            %Get PM VNET setting
            resp = app.nnp.getVNET();
            %fprintf('\nvnet %f', resp);
            if length(resp)==1
                app.PMVNETEditField.Value = resp;
                app.PMVNETEditField.BackgroundColor = [1 1 1];
            else
                app.PMVNETEditField.Value = Inf;
                app.PMVNETEditField.BackgroundColor = [.5 .5 .5];
                
            end
            
            %pause(app.pauseTime);

            %trigger network On            
            app.NetworkButton.Value = 1;
            app.NetworkButtonValueChanged();
            app.enableAll('on');
            
            settings = app.nnp.getRadioSettings;
            if settings.rxTimeout < 30
                resp = questdlg('Current AP Radio Timeout (%u ms) may be too short.  Do you want to set it to deafult of 50ms?');
                if isequal(resp, 'Yes')
                    app.nnp.setRadio('rxTimeout', 50);
                end    
            end
           
        end

        % Button pushed function: ScanforNodesButton
        function ScanforNodesButtonPushed(app, event)
            app.ListBox.Items = {'-'};
            app.enableAll('off');
            if app.AllNodesButton.Value == 1 
                nodes = 1:127;
                h = waitbar(0, 'Scanning for Nodes...');
            else 
                nodes = sscanf( app.NodeScanListTextArea.Value{1}, '%u')';
                h = [];
                if isempty(nodes)
                    msgbox('No nodes listed') 
                    app.enableAll('on');
                    return
                elseif any(nodes>127) || any(nodes<1) 
                    msgbox('Nodes must be between 1 and 127') 
                    app.enableAll('on');
                    return
                end
            end
            
            cnt = 2;
            
            for node = nodes
                if ~isempty(h) 
                    if ~isvalid(h)  %if waitbar closed assume user wants to cancel
                        app.ScanforNodesButton.Text = 'Scan';
                        app.enableAll('on');
                        return;
                        %h = waitbar(node/nodes(end), 'Scanning for Nodes...'); if waitbar was closed, reopen it
                    end
                    waitbar(node/nodes(end), h)
                end
                app.ScanforNodesButton.Text ='Scanning...';
                
                [resp, err] = app.nnp.transmit(7, uint8([0, 0, node, 1, 0]), 0, hex2dec('3c'));
                if err == 0 && length(resp) == 9 && resp(1) == 8 

                    nodeStr = sprintf('Node %u', resp(8));

                    snStr = sprintf('SN %u', (double(resp(4))+double(resp(5))*256));
                    
                    if resp(3)<2 %Bootloader Mode
                        switch resp(7) 
                            case 3,    typeStr = 'Test Device';
                            case 4,    typeStr = 'PulseGenerator (PG4)';
                            case 5,    typeStr = 'Biopotential   (BP2)';
                            case 6,    typeStr = 'SensorHost     (SH6)';
                            otherwise, typeStr = sprintf('Unknown Module Type! 0x%02X', resp(7));
                        end
                        revStr = sprintf('BootRev %u.%u', resp([2 6])); 
                        if resp(3)==1
                            selStr = ' - SELECTED'; 
                        else
                            selStr = [];
                        end
                    elseif resp(3)==2 %App Mode
                         switch resp(2) 
                            case 4,    typeStr = 'PulseGenerator (PG4)';
                            case 5,    typeStr = 'Biopotential   (BP2)';
                            case 6,    typeStr = 'SensorHost     (SH6)';
                            otherwise, typeStr = sprintf('Unknown Module Type! 0x%02X', resp(7));
                         end
                         revStr = sprintf('AppRev %u', double(resp(6))+double(resp(7))*256) ;
                         selStr = ' - RUNNING APP';
                    else
                        typeStr = [];
                        revStr = [];
                        selStr = '- INVALID';
                        %invalid Selection Mode
                    end
                        
                    if resp(9)>1
                        extrasStr = ['(+' num2str(resp(9)-1) , 'other nodes)'];
                    else
                        extrasStr = [];
                    end
                    if resp(8) ~= node
                        extrasStr = [extrasStr, '(expected Node' num2str(resp(8)), ')'];
                    end
                    
                    app.ListBox.Items{cnt} = [nodeStr, ' - ', snStr, ' - ', typeStr, ' - ' revStr, selStr, extrasStr];
                    cnt=cnt+1;
                elseif err~=1
                    if app.AllNodesButton.Value ~= 1
                        app.ListBox.Items{cnt} = sprintf('...may have missed node %u ', node);
                    else
                        app.ListBox.Items{cnt} = sprintf('Node %u - External Error', node);
                    end
                    cnt=cnt+1;
                elseif app.AllNodesButton.Value ~= 1
                    app.ListBox.Items{cnt} = sprintf('Node %u - Not on Network', node);
                    cnt = cnt+1;
                end  
            end
            if ~isempty(h)
                close(h)
            end
            app.ScanforNodesButton.Text = 'Scan';
            app.enableAll('on');
        end

        % Value changed function: SelectNodeButton
        function SelectNodeButtonValueChanged(app, event)
            tryselect = app.SelectNodeButton.Value;
            node = app.NodeEditField.Value;
            
            if tryselect==1
                sn = [0 0];
                app.SelectNodeButton.Text = 'Selecting...';              
            else
                sn = uint8([rem(app.SerialEditField.Value, 256), floor(app.SerialEditField.Value/256)]); %LB, HB
                app.SelectNodeButton.Text = 'Deselecting...';
            end
            
            for i=1:2
                %RM_ESTABLISH_COM
                [resp, err] = app.nnp.transmit(7, uint8([sn, node, 1, 0]), 0, hex2dec('3c'));
                if err
                    if err> 1
                        app.handleErrors(err, 'Select Node');
                    else
                       msgbox('Node may not be on the network. Run a scan to see available nodes.', 'Select Node', 'help') 
                    end
                    if tryselect == 1
                        app.DeselectNode();
                    else
                        app.SelectNode();
                    end
                    return
                end
                
                if length(resp) == 9
                    if resp(1) ~= 8
                        msgbox('Invalid bootloader response length byte', 'RM Bootloader Connect', 'error' );
                        if tryselect == 1
                            app.DeselectNode();
                        else
                            app.SelectNode();
                        end
                        return
                    end
                    
                    if resp(8) ~= node
                         msgbox('Node responding did not match requested node', 'RM Bootloader Connect', 'error' )
                        if tryselect == 1
                            app.DeselectNode();
                        else
                            app.SelectNode();
                        end
                        return
                    end
                    
                    if resp(9) > 1
                        msgbox([num2str(resp(9)) ' nodes matched the requested node.  Connecting to first responder'], 'RM Bootloader Connect' )
                    end
                    
                    selectionstate = resp(3);
                    if tryselect==0
                        if selectionstate ~= 0 
                            msgbox('Node not deselected', 'RM Bootloader Connect', 'error' )
                            app.SelectNode();
                            return
                        elseif  selectionstate == 2
                            msgbox('Node is running Application and is not selected', 'RM Bootloader Connect') 
                        end
                        app.DeselectNode();
                        break; %exit for loop
                    else
                        if selectionstate == 1 && i==1 
                             msgbox('Node is already selected', 'RM Bootloader Connect' )
                        elseif selectionstate == 0 && i==2
                            msgbox('Node not selected', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        elseif selectionstate == 2 
                            msgbox('Node is running Application.  Power cycle network to enter bootloader mode', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        end
                        app.SerialEditField.Value = double(resp(4))+double(resp(5))*256;
                        switch resp(7)
                            case 4,    typeStr = 'PulseGenerator (PG4)'; app.maxAppSize = double(0x1DEFF); app.maxPacketSize = 40; %can be 44 for BP2B/C    
                                app.SetStimLimitsButton.Visible = 'on';
                            case 5,    typeStr = 'Biopotential   (BP2)'; app.maxAppSize = double(0x1DEFF); app.maxPacketSize = 40; %can be 44 for PG4D 
                            case 6,    typeStr = 'SensorHost     (SH6)'; app.maxAppSize = double(0x3EFFF); app.maxPacketSize = 40; 
                            otherwise, typeStr = sprintf('Unknown Module Type! 0x%02X', resp(7));
                        end
                        app.ModuleTypeEditField.Value = typeStr;
                        app.BootRevEditField.Value = num2str(resp(6));
                        sn = resp(4:5);
                        app.SelectNode();
                    end
                elseif length(resp) == 8 && resp(6) <= 56
                    msgbox('Requested node is running old bootloader', 'RM Bootloader Connect', 'warn' );    

                    selectionstate = resp(3);
                    if tryselect==0
                        if selectionstate ~= 0 
                            msgbox('Node not deselected', 'RM Bootloader Connect', 'error' )
                            app.SelectNode();
                            return
                        elseif  selectionstate == 2
                            msgbox('Node is running Application and is not selected', 'RM Bootloader Connect') 
                        end
                        app.DeselectNode();
                        break; %exit for loop
                    else
                        if selectionstate == 1 && i==1 
                             msgbox('Node is already selected', 'RM Bootloader Connect' )
                        elseif selectionstate == 0 && i==2
                            msgbox('Node not selected', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        elseif selectionstate == 2 
                            msgbox('Node is running Application.  Power cycle network to enter bootloader mode', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        end
                        app.SerialEditField.Value = double(resp(4))+double(resp(5))*256;
                        switch resp(7)
                            case 4,    typeStr = 'PulseGenerator (PG4)';
                                app.SetStimLimitsButton.Visible = 'on';
                            case 5,    typeStr = 'Biopotential   (BP2)';
                            case 6,    typeStr = 'SensorHost     (SH6)'; 
                            otherwise, typeStr = sprintf('Unknown Module Type! 0x%02X', resp(7));
                        end
                        app.ModuleTypeEditField.Value = typeStr;
                        app.BootRevEditField.Value = num2str(resp(6));
                        sn = resp(4:5);
                        app.SelectNode();
                    end
                else
                    msgbox('Invalid bootloader response length', 'RM Bootloader Connect', 'error' )
                    %app.SelectNodeButton.Text = errstr;
                    if trySelect == 1
                        app.DeselectNode()
                    else
                        app.SelectNode();
                    end
                    return
                end
            end
            app.enableAll('on');
        end

        % Selection changed function: ScanModeButtonGroup
        function ScanModeButtonGroupSelectionChanged(app, event)
            if app.AllNodesButton.Value == 1 
                app.NodeScanListTextArea.Enable = 'off';
            else
                app.NodeScanListTextArea.Enable = 'on';
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            str = app.ListBox.Value;
            result = sscanf(str, 'Node %u - SN %u');
            
            if length(result)==2
                app.NodeEditField.Value = result(1);
                app.SerialEditField.Value = result(2);
            else
                app.NodeEditField.Value = 0;
                app.SerialEditField.Value = 0;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           sectorSize = 65536; %RM extendede addresses: don't change this
           pageSize = 256;     %RM flash page size: don't change this
           
           while app.PMAppRevisionEditField.Value <0 || app.PMAppRevisionEditField.Value > 65535
               resp = app.nnp.getSwRev(7);
               if length(resp)==1
                    app.PMAppRevisionEditField.Value = resp(1);
                    app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
                    break;
                else
                    app.PMAppRevisionEditField.Value = Inf; 
                    app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
                    userresp = questdlg('Could not get PM App revision.  Try Again?','Get PM App Rev');
                    if isequal(userresp, 'Yes')
                        continue;
                    else
                        warning('PM App rev could not be determined.  May not function correctly')
                        break;
                    end
               end
           end

           if app.PMAppRevisionEditField.Value < 390
               packetSize = 32;    %CE uses 32.  Backwards compatibility for older PM apps
           elseif app.PMAppRevisionEditField.Value < 429
               packetSize = 44;    %CE uses 32.  43-46 requires fewer radio packets, resulting in faster downloads
           else 
               packetSize = app.maxPacketSize;    %base packet size on RM type
           end
           app.enableAll('off');
            
           savedSettings = app.nnp.getRadioSettings;
           savedTimeout = app.nnp.timeout;
           
           %If Write or Verify is selected, get the file before erasing
           if app.WriteCheckBox.Value == 1 || app.VerifyCheckBox.Value ==1
               %Read Binary and parse into packets
                [filename, pathname] = uigetfile('*.bin', 'Select App File', 'MultiSelect', 'off');
                if isempty(pathname) || isempty(filename) || ~ischar(filename) || ~ischar(pathname)
                    msgbox('No file chosen', 'RM Bootloader', 'error');  
                    app.nnp.setRadioSettings(savedSettings);
                    app.nnp.timeout = savedTimeout;
                    app.StartButton.Text = 'Start';
                    app.enableAll('on');
                    return;
                else
                    fid = fopen([pathname filename]);
                    [binary, n] = fread(fid);
                    fclose(fid);
                    if n > app.maxAppSize
                        msgbox('Binary file exceeds max application size for module type', 'RM Bootloader', 'error');  
                        %return; JML commented for testing 
                    end
                    %The RM bootloader does not allow writes to cross page boundaries.  
                    %The packets must therefore break at page boundaries. 
                    nPacketsPerPage = ceil(pageSize/packetSize);
                    nPacketsFullPerPage = floor(pageSize/packetSize);
                    nPacketsPartPerPage = nPacketsPerPage - nPacketsFullPerPage;
                    
                    packetLengthsPerPage = [ones(nPacketsFullPerPage,1)*packetSize;...
                                            ones(nPacketsPartPerPage,1)*rem(pageSize,packetSize)];
                    
                    nPagesFull = floor(n/ pageSize);
                    
                    nFinalPage = rem(n, pageSize);
                    nPacketsFinalPage = ceil(nFinalPage/packetSize);
                    nPacketsFullFinalPage = floor(nFinalPage/packetSize);
                    nPacketsPartFinalPage = nPacketsFinalPage - nPacketsFullFinalPage;
                    
                    packetLengthsFinalPage = [ones(nPacketsFullFinalPage,1)*packetSize;...
                                              ones(nPacketsPartFinalPage,1)*rem(nFinalPage,packetSize)];
                    
                    packetLengths = [repmat(packetLengthsPerPage, nPagesFull, 1); packetLengthsFinalPage];
                    nPackets = length(packetLengths);
                            
                    packets = mat2cell(binary, packetLengths);
                end
           end
           
           if app.EraseCheckBox.Value == 1 || app.BlankCheckCheckBox.Value ==1
                %set longer radio timeout to support erase and bootloader commands
                
                app.nnp.setRadio('rxTimeout', 253, 'Retries', 0); %set to 10s with no auto retries
                app.nnp.timeout = 11;
               
                if app.EraseCheckBox.Value == 1
                    %erase flash
                    app.StartButton.Text = 'Erasing...';
                    drawnow;
                    [resp, err] = app.nnp.transmit(7, uint8([0, 0, 0, 1, 3]), 0, hex2dec('3c'));
                    if err > 0 || length(resp) ~= 2 || ~all(resp==[1 0])
                        if err > 0 
                            app.handleErrors(err, 'Erase Flash Failed');
                        else
                            msgbox('Could not erase for unknown reason. Try again', 'Erase Flash Failed', 'error');
                        end
                        app.nnp.setRadioSettings(savedSettings);
                        app.nnp.timeout = savedTimeout;
                        app.StartButton.Text = 'Start';
                        app.enableAll('on');
                        return;
                    end
                end %end Erase
                
                if app.BlankCheckCheckBox.Value ==1
                    %blankcheck
                    app.StartButton.Text = 'Blank Checking...';
                    drawnow;
                    revPM = app.PMAppRevisionEditField.Value;
                    if revPM > 429
                        maxSector = floor(app.maxAppSize/sectorSize); %sector count starts at 0

                        for sector = 0:maxSector
                            if sector ~= maxSector
                                startAddressSector = 0;
                                endAddressSector = sectorSize;
                            else
                                startAddressSector = 0;
                                endAddressSector = rem(app.maxAppSize, sectorSize);
                            end
                             addrBytes = [floor(startAddressSector/256), rem(startAddressSector, 256), floor(endAddressSector/256), rem(endAddressSector, 256) ];
                            [resp, err] = app.nnp.transmit(7, uint8([0, sector, 0, 5, 6, addrBytes]), 0, hex2dec('3c'));
                            
                            if err > 0 
                                app.handleErrors(err, 'Blankcheck could not run');
                                app.nnp.setRadioSettings(savedSettings);
                                app.nnp.timeout = savedTimeout;
                                app.StartButton.Text = 'Start';
                                app.enableAll('on');
                                return;
                            elseif ~isequal(resp, 0) 
                                if length(resp) == 3 && resp(1)==2
                                    msgbox(sprintf('Blankcheck found non-erased byte at 0x%02X%02X%02X', sector, resp(2:3)), 'Blankcheck Failed', 'error');
                                else
                                    msgbox('Could not complete blankcheck for unknown reason. Try again', 'Blankcheck Failed', 'error');  
                                end
                                app.nnp.setRadioSettings(savedSettings);
                                app.nnp.timeout = savedTimeout;
                                app.StartButton.Text = 'Start';
                                app.enableAll('on');
                                return;
                            end
                        end

                    else
                        [resp, err] = app.nnp.transmit(7, uint8([0, 0, 0, 1, 6]), 0, hex2dec('3c'));
                        if err > 0 
                            app.handleErrors(err, 'Blankcheck could not run');
                            app.nnp.setRadioSettings(savedSettings);
                            app.nnp.timeout = savedTimeout;
                            app.StartButton.Text = 'Start';
                            app.enableAll('on');
                            return;
                        elseif length(resp) ~= 2 || ~all(resp==[1 0])
                            if length(resp) == 4 && resp(1)==3
                                msgbox(sprintf('Blankcheck found non-erased byte at 0x%02X%02X in sector %u (1=lower, 2=upper)', resp(2:end)), 'Blankcheck Failed', 'error');
                            else
                                msgbox('Could not complete blankcheck for unknown reason. Try again', 'Blankcheck Failed', 'error');  
                            end
                            app.nnp.setRadioSettings(savedSettings);
                            app.nnp.timeout = savedTimeout;
                            app.StartButton.Text = 'Start';
                            app.enableAll('on');
                            return;
                        end
                    end
                end %end Blankcheck
                
            
            if app.EraseCheckBox.Value == 1 && app.BlankCheckCheckBox.Value == 1
                str = 'erased and blank checked';
            elseif app.EraseCheckBox.Value == 1
                 str = 'erased (but did not blank check)';
            elseif app.BlankCheckCheckBox.Value == 1
                 str = 'blank checked';
            end
            
            %If not going to Write or Verify, then post message box
            if ~(app.WriteCheckBox.Value == 1 || app.VerifyCheckBox.Value == 1)
                msgbox(['Successfully ' str, ' node ' num2str(app.NodeEditField.Value) '.']);
            end
            
           end %end Erase + Blankcheck  
           
           if app.WriteCheckBox.Value == 1 || app.VerifyCheckBox.Value ==1
                %set to 200ms with retries
                app.nnp.setRadio('rxTimeout', 220, 'Retries', 5); 
                app.nnp.timeout = 1.5;


                if app.WriteCheckBox.Value == 1
                    % Write binary to RM
                    app.StartButton.Text = 'Writing App...';
                    drawnow;
                    h = waitbar(0,'Writing App...');
                    startAddress = 0;
        
                    
                    for i=1:nPackets
                        if ~isvalid(h) %if waitbar was closed, reopen it
                            resp = questdlg('Are you sure you want to cancel writing the app?', 'Write App');
                            if isequal(resp, 'Yes')
                                app.nnp.setRadioSettings(savedSettings);
                                app.nnp.timeout = savedTimeout;
                                app.StartButton.Text = 'Start';
                                app.enableAll('on');
                                return;
                            else
                                h = waitbar(i/nPackets, 'Writing App...'); %recreate waitbar
                            end
                        end
                        waitbar(i/nPackets, h);
                       
                       packetSize = length(packets{i});
                       endAddress = startAddress + packetSize - 1;
                       
                       revPM = app.nnp.getSwRev(7);
                       if revPM > 429
                            sector = floor(startAddress/sectorSize); %sector count starts at 0
                            endSector = floor(endAddress/sectorSize); %sector count starts at 0
                            if sector ~= endSector
                                error('startAddress and endAddress must both be in same sector');
                            end
                            startAddressSector = rem(startAddress, sectorSize);
                            endAddressSector = rem(endAddress, sectorSize);
                            memSelect = 0;
                       else
                           if startAddress > sectorSize-1 && endAddress > sectorSize-1
                                startAddressSector = startAddress - sectorSize;
                                endAddressSector = endAddress - sectorSize;
                                memSelect = 2; %JML this needs to change for PM>429
                           elseif startAddress <= sectorSize-1 && endAddress <= sectorSize-1
                                startAddressSector = startAddress;
                                endAddressSector = endAddress;
                                memSelect = 0; %JML this needs to change for PM>429
                            else
                                error('startAddress and endAddress must both be in same sector');
                           end
                       end
                       
                       %HB, LB
                       retry = 0;
                       while true
                            addrBytes = [floor(startAddressSector/256), rem(startAddressSector, 256), floor(endAddressSector/256), rem(endAddressSector, 256) ];
                            % Write packet to PM, PM will parse to RM
                            if revPM > 429
                                [resp, err] = app.nnp.transmit(7, uint8([0, sector, memSelect, packetSize+4, 2, addrBytes, packets{i}']), 0, hex2dec('3c'));
                            else
                                [resp, err] = app.nnp.transmit(7, uint8([0, 0, memSelect, packetSize+4, 2, addrBytes, packets{i}']), 0, hex2dec('3c'));
                            end

                           if err > 0 || ~isequal(resp, [1 0])
                               % Read back data from the address we last attempted to write to 
                               [resp, err] = app.nnp.transmit(7, uint8([0, 0, memSelect, 6, 5, addrBytes, packetSize]), 0, hex2dec('3c'));
                                if err > 0 || length(resp) ~= packetSize+1 || resp(1)~=packetSize
                                    %error condition on read
                                    disp('Last write packet was unconfirmed and data at address could not be read back')
                                    retry=retry+1;
                                else
                                    if isequal(resp(2:end), packets{i}')
                                        % by reading back data, we confirmed last packet did arrive despite no
                                        % confirmation from write command, jump to next packet
                                        startAddress = startAddress + packetSize;
                                        disp('Last write packet was unconfirmed but confirmed by reading back, continuing')
                                        break;
                                    elseif all(resp(2:end)==255)
                                        % flash is still empty, last write packet did not go through, repeat write attempt at current
                                        % address
                                        retry=retry+1;
                                        disp('Last write packet was unconfirmed and adress is unwritten, retrying')
                                    else 
                                        %flash at current address range is not empty but does not match last packet we
                                        %attempted to write
                                        disp('Last write packet was unconfirmed and data at address does not match.  Perform BlankCheck and retry.')
                                        retry=retry+1; %doesn't really make sense to retry here
                                    end
                                end
                                
                               if retry >= 5
                                   userresp = questdlg('Write last packet failed multiple attempts, Keep trying?');
                                   if isequal(userresp, 'Yes')
                                       retry = 0;
                                       continue;
                                   else
                                       if err > 0 
                                            app.handleErrors(err, 'Write Failed');
                                        else
                                            msgbox('Write Failed due to unknown reason', 'Write Failed', 'error');
                                        end
                                        app.nnp.setRadioSettings(savedSettings);
                                        app.nnp.timeout = savedTimeout;
                                        app.StartButton.Text = 'Start';
                                        close(h);
                                        app.enableAll('on');
                                        return;
                                   end
                               else
                                   continue;
                               end
                           end 
                           % write was confirmed, jump to next packet
                           startAddress = startAddress + packetSize;
                           break;
                       end %retry while loop
                    end %end for loop
 
                    close(h);
                end %end Write
                
    
                if app.VerifyCheckBox.Value == 1
                    % Read back and compare 
                    app.StartButton.Text = 'Verifying App...';
                    drawnow;
                    h = waitbar(0,'Verifying App...');
                    startAddress = 0;
                    
                    for i=1:nPackets
                        if ~isvalid(h) %if waitbar was closed, reopen it
                            resp = questdlg('Are you sure you want to cancel verifying the app?', 'Verify App');
                            if isequal(resp, 'Yes')
                                app.nnp.setRadioSettings(savedSettings);
                                app.nnp.timeout = savedTimeout;
                                app.StartButton.Text = 'Start';
                                app.enableAll('on');
                                return;
                            else
                                h = waitbar(i/nPackets, 'Verifying App...');
                            end
                        end
                        waitbar(i/nPackets, h);
                       
                       packetSize = length(packets{i});
                       endAddress = startAddress + packetSize - 1;
                        %HB, LB
                        
                        if startAddress > sectorSize - 1 && endAddress > sectorSize - 1
                            startAddressSector = startAddress - sectorSize;
                            endAddressSector = endAddress - sectorSize;
                            memSelect = 2; %JML memselect?
                        elseif startAddress <= sectorSize - 1 && endAddress <= sectorSize - 1
                            startAddressSector = startAddress;
                            endAddressSector = endAddress;
                            memSelect = 0; %JML memselect?
                        else
                            error('startAddress and endAddress must both be in same sector');
                        end
                        
                        retry=0;
                        app.nnp.setRadio('rxTimeout', 200, 'Retries', 1); 
                        app.nnp.timeout = 0.25;
                        while true
                            addrBytes = [floor(startAddressSector/256), rem(startAddressSector, 256), floor(endAddressSector/256), rem(endAddressSector, 256) ];
                           [resp, err] = app.nnp.transmit(7, uint8([0, 0, memSelect, 6, 5, addrBytes, packetSize]), 0, hex2dec('3c'));
                        
                           if err > 0 || length(resp) ~= packetSize+1 || resp(1)~=packetSize
                                retry = retry+1;
                                pause(0.1)
                                warning('retrying');
                                if retry > 5
                                    if err > 0 
                                        app.handleErrors(err, 'Read Failed');
                                    else
                                        msgbox('Read Failed due to unknown reason', 'Read Failed', 'error');
                                    end
                                    app.nnp.setRadioSettings(savedSettings);
                                    app.nnp.timeout = savedTimeout;
                                    app.StartButton.Text = 'Start';
                                    close(h);
                                    app.enableAll('on');
                                    return;
                                end
                           else
                                index = find(resp(2:end)~=packets{i}',1); %find one-based index (within packet) of first non matching element
                                if ~isempty(index)
                                    msgbox(sprintf('Verify Failed due to mismatch at 0x%05X', startAddress+index-1), 'Verify Failed', 'error');
                                    app.nnp.setRadioSettings(savedSettings);
                                    app.nnp.timeout = savedTimeout;
                                    app.StartButton.Text = 'Start';
                                    close(h);
                                    app.enableAll('on');
                                    return;
                                end
                                break;
                           end
                        end
                       
                       startAddress = startAddress + packetSize;
                    end %end for loop
                    
                    close(h);
                end %end Verify
            
                if app.EraseCheckBox.Value == 1 || app.BlankCheckCheckBox.Value == 1
                    str = [str, ', ' ];
                else
                    str = [];
                end
                
                if app.WriteCheckBox.Value == 1 && app.VerifyCheckBox.Value == 1
                    str = [str 'wrote and verified'];
                elseif app.WriteCheckBox.Value == 1 
                     str = [str 'wrote (but did not verify)'];
                elseif app.VerifyCheckBox.Value == 1
                     str = [str 'verified'];
                end
                
                 msgbox(['Successfully ' str, ' "', filename, '" on node ' num2str(app.NodeEditField.Value) '.']);
            end %end Write + Verify


            %restore radio settings
            app.nnp.setRadioSettings(savedSettings);
            app.nnp.timeout = savedTimeout;
            app.StartButton.Text = 'Start';
            app.enableAll('on');
            
        end

        % Button pushed function: ChangeNodeButton
        function ChangeNodeButtonPushed(app, event)
            resp = inputdlg(['Current Node # for selected node is', num2str(app.NodeEditField.Value), '. New Node #:'], 'Change Node',1);
            try newNode = str2double(resp{1});
                if newNode <1 || newNode >127 
                    msgbox('Node must be between 1 and 127','ChangeNode', 'error')
                    return;
                end
                 [resp, err] = app.nnp.transmit(7, uint8([0, 0, newNode, 1, 7]), 0, hex2dec('3c'));
                 if err > 0 || length(resp) ~= 2 || ~all(resp==[1 newNode])
                     if err > 0 
                        app.handleErrors(err, 'Change Node Failed');
                     else
                          msgbox('Node was not changed for unknown reason','ChangeNode', 'error')
                     end
                     return;
                 else
                    app.NodeEditField.Value = newNode; 
                 end
            catch
                msgbox('Node number not valid','ChangeNode', 'error')
                return;
            end
           
        end

        % Value changed function: PMVNETEditField
        function PMVNETEditFieldValueChanged(app, event)
            value = app.PMVNETEditField.Value;
            if value > 9.5 || value < 4.6
                msgbox('PM Network Voltage must be between 4.6 and 9.5 V')
            elseif ~app.nnp.setVNET(value)
                msgbox('Could not set PM Network Voltage')
            end
            resp = app.nnp.getVNET();
            if length(resp)==1
                app.PMVNETEditField.Value = resp;
            else
                msgbox('Could not read back PM Network Voltage')
            end
        end

        % Value changed function: NetworkButton
        function NetworkButtonValueChanged(app, event)
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM.  Network Setting may not work');
            end
            if app.NetworkButton.Value 
                if ~app.nnp.networkOnBootloader()
                    msgbox('could not turn on Network.');
                end
                pause(0.2); %pause after tunring on Network before confirming
            else
                if ~ app.nnp.networkOff()
                    msgbox('could not turn off Network.');
                else
                    app.DeselectNode();
                end
            end
            %read back network status?
            
            resp = app.nnp.getNetworkStatus();
            if isempty(resp)
                msgbox('Could not confirm Network Status. Toggle Network button to update');
                app.NetworkButton.Text = {'Network'; 'Status'; 'is Unknown'};
            elseif resp == 0
                app.NetworkButton.Text = {'Network'; 'is'; 'OFF'};
                app.NetworkButton.Value = 0;
            else 
                app.NetworkButton.Text = {'Network'; 'is'; 'ON'};
                app.NetworkButton.Value = 1;
            end
            
            app.enableAll('on'); %update button enables based on network status
            
        end

        % Button pushed function: StartSelectedButton
        function StartSelectedButtonPushed(app, event)
            if app.RestoreODDefaultsCheckBox.Value == 1
              [resp, err] = app.nnp.transmit(7, uint8([0, 0, 0, 1, 10]), 0, hex2dec('3c'));
              if err > 0 || length(resp) ~= 2 || ~all(resp==[1 0])
                 if err > 0 
                    app.handleErrors(err, 'Restore OD Defaults Failed');
                 else
                      msgbox('Restore OD Defaults failed for unknown reason','RM Bootloader', 'error')
                 end
              end
            end
              
            [resp, err] = app.nnp.transmit(7, uint8([0, 0, 0, 1, 4]), 0, hex2dec('3c'));
              if err > 0 || length(resp) ~= 2 || ~all(resp==[1 0])
                  if err > 0 
                      app.handleErrors(err, 'Start Selected Failed');
                  else
                      msgbox('Start Selected failed for unknown reason','RM Bootloader', 'error')
                 end
              else
                  app.DeselectNode(); %starting node deselects it from bootloader
              end           
        end

        % Button pushed function: StartAllButton
        function StartAllButtonPushed(app, event)
                       
             [resp, err] = app.nnp.transmit(7, uint8([0, 0, 0, 1, 15]), 0, hex2dec('3c'));
              if err > 0 || length(resp) ~= 2 || ~all(resp==[1 0])
                  if err > 0 
                            app.handleErrors(err, 'Start All Failed');
                  else
                      msgbox('Start All failed for unknown reason','RM Bootloader', 'error')
                  end
              end
              
              app.DeselectNode(); %starting node deselects it from bootloader
        end

        % Close request function: RMBootloader
        function RMBootloaderCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Button pushed function: SetStimLimitsButton
        function SetStimLimitsButtonPushed(app, event)
            %%Read 32 bytes of protected flash

            packetSize = 32;
            pageCommand = 4; %protected flash
            startAddressPage = 0xdf00;
            endAddressPage = startAddressPage + packetSize - 1;
                        
            addrBytes = [floor(startAddressPage/256), rem(startAddressPage, 256), floor(endAddressPage/256), rem(endAddressPage, 256) ];
            [resp, err] = app.nnp.transmit(7, uint8([0, 0, pageCommand, 6, 5, addrBytes, packetSize]), 0, hex2dec('3c'));
            if err || length(resp) ~= packetSize+1 || resp(1) ~= packetSize
                msgbox('Failed to read current settings, try again');
                return;
            end
            data = resp(2:end);
            stimmax = data(7:14);
            definput = cell(1, 8);
            for i=1:8
                definput{i} = num2str(stimmax(i));
            end
            while true
                prompt = {'CH1 PW max (us)', 'CH1 Amp max (0.1mA, 200=20mA)','CH2 PW max (us)', 'CH2 Amp max (0.1mA, 200=20mA)' ,'CH3 PW max (us)', 'CH3 Amp max (0.1mA, 200=20mA)' ,'CH4 PW max (us)', 'CH4 Amp max (0.1mA, 200=20mA)'   };
                userresp = inputdlg(prompt, 'Max Stim Settings', [1 50], definput);
                if isempty(userresp)
                    break;
                else
                    stimmaxnew = str2double(userresp)';
                    if all(ismember(stimmaxnew, 1:255))
                        if ~isequal(uint8(stimmaxnew), stimmax)
                            writeData = [data(1:6) uint8(stimmaxnew)];
                            packetSize = length(writeData);
                            pageCommand = 4; %protected flash
                            startAddressPage = 0xdf00;
                            endAddressPage = startAddressPage + packetSize;
                            addrBytes = [floor(startAddressPage/256), rem(startAddressPage, 256), floor(endAddressPage/256), rem(endAddressPage, 256) ];
                            [resp, err] = app.nnp.transmit(7, uint8([0, 0, pageCommand, 5+packetSize, 2, addrBytes, writeData]), 0, hex2dec('3c'));
                            if ~isequal(resp, [1 0])
                                msgbox('Failed to write settings, try again')
                            end
                        end
                        break;
                    else 
                        %bad entry, show dialog again
                    end
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RMBootloader = uifigure('Visible', 'off');
            app.RMBootloader.Name = 'Remote Module Bootloader';
            app.RMBootloader.CloseRequestFcn = createCallbackFcn(app, @RMBootloaderCloseRequest, true);
            app.NodeLabel = uilabel(app.RMBootloader);
            app.NodeLabel.Text = 'Node #';
            app.NodeEditField = uieditfield(app.RMBootloader, 'numeric');
            app.NodeEditField.Limits = [0 127];
            app.NodeEditField.Value = 115;
            app.ScanforNodesButton = uibutton(app.RMBootloader, 'push');
            app.ScanforNodesButton.ButtonPushedFcn = createCallbackFcn(app, @ScanforNodesButtonPushed, true);
            app.ScanforNodesButton.Text = 'Scan for Nodes';
            app.ListBox = uilistbox(app.RMBootloader);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ScanModeButtonGroup = uibuttongroup(app.RMBootloader);
            app.ScanModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScanModeButtonGroupSelectionChanged, true);
            app.ScanModeButtonGroup.Title = 'Scan Mode';
            app.AllNodesButton = uiradiobutton(app.ScanModeButtonGroup);
            app.AllNodesButton.Text = 'All Nodes';
            app.AllNodesButton.Value = true;
            app.ListedNodesButton = uiradiobutton(app.ScanModeButtonGroup);
            app.ListedNodesButton.Text = 'Listed Nodes';
            app.ContinuousButton = uiradiobutton(app.ScanModeButtonGroup);
            app.ContinuousButton.Visible = 'off';
            app.ContinuousButton.Text = 'Continuous';
            app.SerialEditFieldLabel = uilabel(app.RMBootloader);
            app.SerialEditFieldLabel.Text = 'Serial #';
            app.SerialEditField = uieditfield(app.RMBootloader, 'numeric');
            app.SerialEditField.Editable = 'off';
            app.PowerModuleSettingsPanel = uipanel(app.RMBootloader);
            app.PowerModuleSettingsPanel.Title = 'Power Module Settings';
            app.NetworkButton = uibutton(app.PowerModuleSettingsPanel, 'state');
            app.NetworkButton.ValueChangedFcn = createCallbackFcn(app, @NetworkButtonValueChanged, true);
            app.NetworkButton.Text = 'Network ';
            app.SerialEditField_2Label = uilabel(app.PowerModuleSettingsPanel);
            app.SerialEditField_2Label.Text = 'Serial #';
            app.PMPCBSerialEditField = uieditfield(app.PowerModuleSettingsPanel, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.AppRevEditFieldLabel = uilabel(app.PowerModuleSettingsPanel);
            app.AppRevEditFieldLabel.Text = 'App Rev';
            app.PMAppRevisionEditField = uieditfield(app.PowerModuleSettingsPanel, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.VNETLabel = uilabel(app.PowerModuleSettingsPanel);
            app.VNETLabel.Text = 'VNET';
            app.PMVNETEditField = uieditfield(app.PowerModuleSettingsPanel, 'numeric');
            app.PMVNETEditField.ValueChangedFcn = createCallbackFcn(app, @PMVNETEditFieldValueChanged, true);
            app.PMVNETEditField.Value = Inf;
            app.SelectNodeButton = uibutton(app.RMBootloader, 'state');
            app.SelectNodeButton.ValueChangedFcn = createCallbackFcn(app, @SelectNodeButtonValueChanged, true);
            app.SelectNodeButton.Text = 'Select Node';
            app.NodeScanListTextAreaLabel = uilabel(app.RMBootloader);
            app.NodeScanListTextAreaLabel.Text = 'Node Scan List';
            app.NodeScanListTextArea = uitextarea(app.RMBootloader);
            app.NodeScanListTextArea.Value = {'1 2 3 4 5'};
            app.ChangeNodeButton = uibutton(app.RMBootloader, 'push');
            app.ChangeNodeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeNodeButtonPushed, true);
            app.ChangeNodeButton.Text = 'Change Node #';
            app.ModuleTypeEditFieldLabel = uilabel(app.RMBootloader);
            app.ModuleTypeEditFieldLabel.Text = 'Module Type';
            app.ModuleTypeEditField = uieditfield(app.RMBootloader, 'text');
            app.ModuleTypeEditField.Editable = 'off';
            app.ExitBootloaderStartAppPanel = uipanel(app.RMBootloader);
            app.ExitBootloaderStartAppPanel.Title = 'Exit Bootloader/Start App';
            app.StartSelectedButton = uibutton(app.ExitBootloaderStartAppPanel, 'push');
            app.StartSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @StartSelectedButtonPushed, true);
            app.StartSelectedButton.Text = 'Selected Node';
            app.StartAllButton = uibutton(app.ExitBootloaderStartAppPanel, 'push');
            app.StartAllButton.ButtonPushedFcn = createCallbackFcn(app, @StartAllButtonPushed, true);
            app.StartAllButton.Text = 'All Nodes';
            app.RestoreODDefaultsCheckBox = uicheckbox(app.ExitBootloaderStartAppPanel);
            app.RestoreODDefaultsCheckBox.Text = 'Restore OD Defaults';
            app.RestoreODDefaultsCheckBox.Value = true;
            app.RemoteModuleFlashPanel = uipanel(app.RMBootloader);
            app.RemoteModuleFlashPanel.Title = 'Remote Module Flash';
            app.StartButton = uibutton(app.RemoteModuleFlashPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EraseCheckBox = uicheckbox(app.RemoteModuleFlashPanel);
            app.EraseCheckBox.Text = 'Erase';
            app.EraseCheckBox.Value = true;
            app.BlankCheckCheckBox = uicheckbox(app.RemoteModuleFlashPanel);
            app.BlankCheckCheckBox.Text = 'BlankCheck';
            app.BlankCheckCheckBox.Value = true;
            app.WriteCheckBox = uicheckbox(app.RemoteModuleFlashPanel);
            app.WriteCheckBox.Text = 'Write';
            app.WriteCheckBox.Value = true;
            app.VerifyCheckBox = uicheckbox(app.RemoteModuleFlashPanel);
            app.VerifyCheckBox.Text = 'Verify';
            app.VerifyCheckBox.Value = true;
            app.BootRevEditFieldLabel = uilabel(app.RMBootloader);
            app.BootRevEditFieldLabel.Text = 'Boot Rev';
            app.BootRevEditField = uieditfield(app.RMBootloader, 'text');
            app.BootRevEditField.Editable = 'off';
            app.SupportsPM430Label = uilabel(app.RMBootloader);
            app.SupportsPM430Label.Text = 'Supports PM 430';
            app.SetStimLimitsButton = uibutton(app.RMBootloader, 'push');
            app.SetStimLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetStimLimitsButtonPushed, true);
            app.SetStimLimitsButton.Visible = 'off';
            app.SetStimLimitsButton.Text = 'Set Stim Limits';
            % Show the figure after all components are created
            app.RMBootloader.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rmbootloader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RMBootloader)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RMBootloader)
        end
    end
end